classdef Scube < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        main                           matlab.ui.Figure
        SolventpartPanel               matlab.ui.container.Panel
        SolventDropDownLabel           matlab.ui.control.Label
        Solvent                        matlab.ui.control.DropDown
        SolutepartPanel                matlab.ui.container.Panel
        ReactantMoleculargeometryfilesLabel  matlab.ui.control.Label
        FileGro                        matlab.ui.control.TextArea
        ConcentrationofsolutemMLabel   matlab.ui.control.Label
        Conc                           matlab.ui.control.NumericEditField
        Excitationratiostructurallychangedsolutetotalsolute1Label  matlab.ui.control.Label
        Exct                           matlab.ui.control.NumericEditField
        ProductMoleculargeometryfilesLabel  matlab.ui.control.Label
        FilePro                        matlab.ui.control.TextArea
        Draw                           matlab.ui.control.Button
        GetReactantButton              matlab.ui.control.Button
        GetProductButton               matlab.ui.control.Button
        FractionLabel                  matlab.ui.control.Label
        FractionGro                    matlab.ui.control.EditField
        FractionLabel_2                matlab.ui.control.Label
        FractionPro                    matlab.ui.control.EditField
        ReactantGroundstateLabel       matlab.ui.control.Label
        ProductIntermediateLabel       matlab.ui.control.Label
        Thermalexcitationratiostructuralchangedsolutetotalsolute1Label  matlab.ui.control.Label
        VibCool                        matlab.ui.control.NumericEditField
        SolventselectPanel             matlab.ui.container.Panel
        WavelengthofpumplasernmEditFieldLabel  matlab.ui.control.Label
        Wave                           matlab.ui.control.NumericEditField
        dSdTLabel                      matlab.ui.control.Label
        dtSwitch                       matlab.ui.control.ToggleSwitch
        dSdrhoLabel                    matlab.ui.control.Label
        drhoSwitch                     matlab.ui.control.ToggleSwitch
        NoiseparPenel                  matlab.ui.container.Panel
        CollectiontimeshoursLabel      matlab.ui.control.Label
        Hour                           matlab.ui.control.NumericEditField
        PhotonspulseLabel              matlab.ui.control.Label
        PhotonsPerPulse                matlab.ui.control.NumericEditField
        PulserepetitionrateHzLabel     matlab.ui.control.Label
        PulseRepetition                matlab.ui.control.NumericEditField
        DutycycleLabel                 matlab.ui.control.Label
        DutyCycle                      matlab.ui.control.NumericEditField
        ExposuretimessLabel            matlab.ui.control.Label
        ExposureTime                   matlab.ui.control.NumericEditField
        SampletodetectordistancemmLabel  matlab.ui.control.Label
        SampleDistance                 matlab.ui.control.NumericEditField
        save                           matlab.ui.container.Panel
        WorkingDirectory               matlab.ui.control.EditField
        GetDirectoryButton             matlab.ui.control.Button
        MakeResultsCheckBox            matlab.ui.control.CheckBox
        NoisepartPanel                 matlab.ui.container.Panel
        NoiseFileEditField             matlab.ui.control.EditField
        GetNoiseFileButton             matlab.ui.control.Button
        SwitchLabel                    matlab.ui.control.Label
        NoiseFreeSwitch                matlab.ui.control.Switch
        dqEditFieldLabel               matlab.ui.control.Label
        dqEditField                    matlab.ui.control.NumericEditField
        qMAXEditFieldLabel             matlab.ui.control.Label
        qMAXEditField                  matlab.ui.control.NumericEditField
        qMINEditFieldLabel             matlab.ui.control.Label
        qMINEditField                  matlab.ui.control.NumericEditField
        ConfigurationsFilesPanel       matlab.ui.container.Panel
        ConfigurationFileEditField     matlab.ui.control.TextArea
        LoadConfigurationButton        matlab.ui.control.Button
        SaveConfigurationButton        matlab.ui.control.Button
        SimulateScubeButton            matlab.ui.control.Button
        ThecageSoluteSolventcrosstermPanel  matlab.ui.container.Panel
        CagefilesnameLabel             matlab.ui.control.Label
        CageFileEditField              matlab.ui.control.EditField
        CageprocessmethodKnobLabel     matlab.ui.control.Label
        CageMethodknob                 matlab.ui.control.DiscreteKnob
        GetCageButton                  matlab.ui.control.Button
        SamplingdrEditFieldLabel       matlab.ui.control.Label
        SamplingDrEditField            matlab.ui.control.NumericEditField
        SimulateScubeBatchButton       matlab.ui.control.Button
        SensitivityplotsPanel          matlab.ui.container.Panel
        AtomicPositionLabel            matlab.ui.control.Label
        AtomicPositionSwitch           matlab.ui.control.RockerSwitch
        InternuclearDistanceSwitchLabel  matlab.ui.control.Label
        InternuclearDistanceSwitch     matlab.ui.control.RockerSwitch
        AtomicPositionLamp             matlab.ui.control.Lamp
        InternuclearDistanceLamp       matlab.ui.control.Lamp
        SpiralsamplingnumberEditFieldLabel  matlab.ui.control.Label
        SpiralSamplingNumberEditField  matlab.ui.control.NumericEditField
        ChangingnucleardistanceLabel   matlab.ui.control.Label
        ChangingNuclearDistanceEditField  matlab.ui.control.NumericEditField
        SpiralradiusLabel              matlab.ui.control.Label
        SpiralRadiusEditField          matlab.ui.control.NumericEditField
        SimulateSensitivityButton      matlab.ui.control.Button
        Image                          matlab.ui.control.Image
        Image2                         matlab.ui.control.Image
    end

    
    properties (Access = public)
        config % Description
        constant % Description
        results % Description
        cal % Description
        sensitivity % Description
    end
    
    properties (Access = private)
    end
    
    methods (Access = private)
        
        function ReadConfig(app,path,file,~)
            if 0==file
                return
            end
            FileName=sprintf('%s/%s',path,file);
            load(FileName,'-mat'); %#ok<*LOAD>
            
            app.Solvent.Value=configSave.Solvent.Value;
            SolventValueChanged(app);
            app.FileGro.Value=configSave.FileGro.Value;
            FileGroValueChanged(app);
            app.Conc.Value=configSave.Conc.Value;
            ConcValueChanged(app);
            app.Exct.Value=configSave.Exct.Value;
            ExctValueChanged(app);
            app.FilePro.Value=configSave.FilePro.Value;
            FileProValueChanged(app);
            
            app.FractionGro.Value=configSave.FractionGro.Value;
            FractionGroValueChanged(app);
            app.FractionPro.Value=configSave.FractionPro.Value;
            FractionProValueChanged(app);
            app.VibCool.Value=configSave.VibCool.Value;
            VibCoolValueChanged(app);
            app.Wave.Value=configSave.Wave.Value;
            WaveValueChanged(app);
            app.dtSwitch.Value=configSave.dtSwitch.Value;
            dtSwitchValueChanged(app);
            
            app.drhoSwitch.Value=configSave.drhoSwitch.Value;
            drhoSwitchValueChanged(app);
            app.Hour.Value=configSave.Hour.Value;
            HourValueChanged(app);
            app.PhotonsPerPulse.Value=configSave.PhotonsPerPulse.Value;
            PhotonsPerPulseValueChanged(app);
            app.PulseRepetition.Value=configSave.PulseRepetition.Value;
            PulseRepetitionValueChanged(app);
            app.DutyCycle.Value=configSave.DutyCycle.Value;
            DutyCycleValueChanged(app);
            
            app.ExposureTime.Value=configSave.ExposureTime.Value;
            ExposureTimeValueChanged(app);
            app.SampleDistance.Value=configSave.SampleDistance.Value;
            SampleDistanceValueChanged(app);
            app.WorkingDirectory.Value=configSave.WorkingDirectory.Value;
            WorkingDirectoryValueChanged(app);
            app.MakeResultsCheckBox.Value=configSave.MakeResultsCheckBox.Value;
            MakeResultsCheckBoxValueChanged(app);
            app.NoiseFileEditField.Value=configSave.NoiseFileEditField.Value;
            NoiseFileEditFieldValueChanged(app);
            
            app.NoiseFreeSwitch.Value=configSave.NoiseFreeSwitch.Value;
            NoiseFreeSwitchValueChanged(app);
            app.dqEditField.Value=configSave.dqEditField.Value;
            dqEditFieldValueChanged(app);
            app.qMAXEditField.Value=configSave.qMAXEditField.Value;
            qMAXEditFieldValueChanged(app);
            app.CageFileEditField.Value=configSave.CageFileEditField.Value;
            CageFileEditFieldValueChanged(app);
            app.CageMethodknob.Value=configSave.CageMethodknob.Value;
            CageMethodknobValueChanged(app);
            
            
            app.SamplingDrEditField.Value=configSave.SamplingDrEditField.Value;
            SamplingDrEditFieldValueChanged(app);
            app.AtomicPositionSwitch.Value=configSave.AtomicPositionSwitch.Value;
            AtomicPositionSwitchValueChanged(app);
            app.InternuclearDistanceSwitch.Value=configSave.InternuclearDistanceSwitch.Value;
            InternuclearDistanceSwitchValueChanged(app);
            app.SpiralSamplingNumberEditField.Value=configSave.SpiralSamplingNumberEditField.Value;
            SpiralSamplingNumberEditFieldValueChanged(app);
            app.ChangingNuclearDistanceEditField.Value=configSave.ChangingNuclearDistanceEditField.Value;
            ChangingNuclearDistanceEditFieldValueChanged(app);
            app.SpiralRadiusEditField.Value=configSave.SpiralRadiusEditField.Value;
            SpiralRadiusEditFieldValueChanged(app);
            
%             app.qMINEditField.Value
            app.qMINEditField.Value=configSave.qMINEditField.Value;
            qMINEditFieldValueChanged(app);
            app.qMAXEditField.Value=configSave.qMAXEditField.Value;
            qMAXEditFieldValueChanged(app);


            %     app.AtomicPositionLamp.Value=configSave.AtomicPositionLamp.Value;
            %     AtomicPosition
            %     app.InternuclearDistanceLamp.Value=configSave.InternuclearDistanceLamp.Value;


            % %             app.FilePro.Value='path';
            % %             app.FileGro.Value='path';
            %
            %             FileName=sprintf('%s/%s',path,file);
            % %             ID=fopen(FileName);
            % %             data_temp=textscan(ID,'%s','Delimiter','\n');
            % %             data=data_temp{1};
            %
            %             load(FileName); %#ok<*LOAD>
            %
            % %             numgnd=str2double(data{2});
            % %             numpro=str2double(data{5});
            % %
            % %             for ii=1:numgnd
            % %                 app.FileGro.Value{ii}=data{7+ii};
            % %             end
            % %
            % %             for ii=1:numpro
            % %                 app.FilePro.Value{ii}=data{9+numgnd+ii};
            % %             end
            % %
            % %             app.Solvent.Value=data{12+numgnd+numpro};
            % %
            % %             app.Conc.Value=str2double(data{15+numgnd+numpro});
            % %             ConcValueChanged(app);
            % %
            % %             app.Exct.Value=str2double(data{18+numgnd+numpro});
            % %             ExctValueChanged(app);
            % %
            % %             app.Wave.Value=str2double(data{21+numgnd+numpro});
            % %
            % %             app.Hour.Value=str2double(data{24+numgnd+numpro});
            % %             HourValueChanged(app);
            % %
            % %             app.PhotonsPerPulse.Value=str2double(data{27+numgnd+numpro});
            % %             PhotonsPerPulseValueChanged(app);
            % %
            % %             app.PulseRepetition.Value=str2double(data{30+numgnd+numpro});
            % %             PulseRepetitionValueChanged(app);
            % %
            % %             %app.ExposureTime.Value=str2double(data{33+numgnd+numpro});
            % %             %ExposureTimeValueChanged(app);
            % %
            % %             app.DutyCycle.Value=str2double(data{36+numgnd+numpro});
            % %             DutyCycleValueChanged(app);
            % %
            % %             app.NoiseFileEditField.Value=data{39+numgnd+numpro};
            % %             app.FractionGro.Value=data{42+numgnd+numpro};
            % %             app.FractionPro.Value=data{45+numgnd+numpro};
            % %             app.VibCool.Value=str2double(data{48+numgnd+numpro});
            % %             app.WorkingDirectory.Value=data{51+numgnd+numpro};
            % %             app.dtSwitch.Value=data{54+numgnd+numpro};
            % %             dtSwitchValueChanged(app);
            % %             app.drhoSwitch.Value=data{57+numgnd+numpro};
            % %             drhoSwitchValueChanged(app);
            % %             app.SampleDistance.Value=str2double(data{60+numgnd+numpro});
            % %             SampleDistanceValueChanged(app); %% Temp
            % %             app.CageMethodknob.Value=data{63+numgnd+numpro};
            % %             CageMethodknobValueChanged(app);
            % %             app.CageFileEditField.Value=data{66+numgnd+numpro};
            
            
            switch nargin
                case 3
                    temp=sprintf('Loading path : %s',FileName);
                    msgbox({'Configuration file loaded successfully' temp},'Scube')
                case 4
            end
        end
        
        function WriteConfig(app,time,path)
            %             save('test.mat','-struct','app','-v7.3','-nocompression');
            
            
            switch nargin
                case 3
%                     ResultsFolder=sprintf('%s',path);
                    [~,~]=mkdir(path);
                    NameFile=sprintf('%s/Scube_config_%s.mat',path,time);
                case 2
                    
                    ResultsFolder=sprintf('%s/Scube_configs_%s',path,time);
                    [~,~]=mkdir(ResultsFolder);
                    NameFile=sprintf('%s/Scube_config.mat',ResultsFolder);
                    
                    
%                     [~,~]=mkdir('config');
%                     NameFile=sprintf('config/Scube_config_%s.mat',time);
            end
            
            %             ID_file=fopen(NameFile,'w');
            
            try
                configSave.Solvent.Value=app.Solvent.Value;
                configSave.FileGro.Value=app.FileGro.Value;
                configSave.Conc.Value=app.Conc.Value;
                configSave.Exct.Value=app.Exct.Value;
                configSave.FilePro.Value=app.FilePro.Value;
                
                configSave.FractionGro.Value=app.FractionGro.Value;
                configSave.FractionPro.Value=app.FractionPro.Value;
                configSave.VibCool.Value=app.VibCool.Value;
                configSave.Wave.Value=app.Wave.Value;
                configSave.dtSwitch.Value=app.dtSwitch.Value;
                
                configSave.drhoSwitch.Value=app.drhoSwitch.Value;
                configSave.Hour.Value=app.Hour.Value;
                configSave.PhotonsPerPulse.Value=app.PhotonsPerPulse.Value;
                configSave.PulseRepetition.Value=app.PulseRepetition.Value;
                configSave.DutyCycle.Value=app.DutyCycle.Value;
                
                configSave.ExposureTime.Value=app.ExposureTime.Value;
                configSave.SampleDistance.Value=app.SampleDistance.Value;
                configSave.WorkingDirectory.Value=app.WorkingDirectory.Value;
                configSave.MakeResultsCheckBox.Value=app.MakeResultsCheckBox.Value;
                configSave.NoiseFileEditField.Value=app.NoiseFileEditField.Value;
                
                configSave.NoiseFreeSwitch.Value=app.NoiseFreeSwitch.Value;
                configSave.dqEditField.Value=app.dqEditField.Value;
                configSave.qMAXEditField.Value=app.qMAXEditField.Value;
                configSave.CageFileEditField.Value=app.CageFileEditField.Value;
                configSave.CageMethodknob.Value=app.CageMethodknob.Value;
                
                configSave.SamplingDrEditField.Value=app.SamplingDrEditField.Value;
                configSave.AtomicPositionSwitch.Value=app.AtomicPositionSwitch.Value;
                configSave.InternuclearDistanceSwitch.Value=app.InternuclearDistanceSwitch.Value;
                configSave.SpiralSamplingNumberEditField.Value=app.SpiralSamplingNumberEditField.Value;
                configSave.ChangingNuclearDistanceEditField.Value=app.ChangingNuclearDistanceEditField.Value;
                
                configSave.SpiralRadiusEditField.Value=app.SpiralRadiusEditField.Value;
                configSave.qMINEditField.Value=app.qMINEditField.Value;
%                 configSave.qMINEditField.Value=app.qMINEditField.Value;
                %     configSave.AtomicPositionLamp.Value=app.AtomicPositionLamp.Value;
                %     configSave.InternuclearDistanceLamp.Value=app.InternuclearDistanceLamp.Value;
                
                save(NameFile,'configSave'); %#ok<*ADPROPLC>
                
                %                 fprintf(ID_file,'The_number_of_reatants\n');
            catch
                error('the wrong working directory. Check working directory.');
            end
            
            %             temp=sprintf('%d\n\n',length(app.FileGro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'The_number_of_products_intemediates\n');
            %             temp=sprintf('%d\n\n',length(app.FilePro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Reactants_atomic_coordination_files\n');
            %
            %             for ii=1:length(app.FileGro.Value)
            %                 temp=sprintf('%s\n',app.FileGro.Value{ii});
            %                 fprintf(ID_file,'%s',temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'Products_intemediates_atomic_coordination_files\n');
            %
            %             for ii=1:length(app.FilePro.Value)
            %                 temp=sprintf('%s\n',app.FilePro.Value{ii});
            %                 fprintf(ID_file,'%s',temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'Solvent\n');
            %             temp=sprintf('%s\n\n',app.Solvent.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Concentration_of_solutes\n');
            %             temp=sprintf('%.4g\n\n',app.Conc.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Excitation_ratio_structural_changes\n');
            %             temp=sprintf('%.4g\n\n',app.Exct.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Wavelength_of_pump_laser\n');
            %             temp=sprintf('%.4g\n\n',app.Wave.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Collection_times(hours)\n');
            %             temp=sprintf('%.4g\n\n',app.Hour.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Photons/photons_per_pulse\n');
            %             temp=sprintf('%d\n\n',app.PhotonsPerPulse.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Repetition_Rate(Hz)\n');
            %             temp=sprintf('%d\n\n',app.PulseRepetition.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Exposure_time\n');
            %             temp=sprintf('%d\n\n',app.ExposureTime.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Duty_cycle\n');
            %             temp=sprintf('%.4g\n\n',app.DutyCycle.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Solvent_stadard_deviation_mfile\n');
            %             temp=sprintf('%s\n\n',app.NoiseFileEditField.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'Fraction_of_reactants\n');
            %             temp=sprintf('%s\n\n',app.FractionGro.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'Fraction_of_products\n');
            %             temp=sprintf('%s\n\n',app.FractionPro.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'Excitation_ratio_thermal_changes_only\n');
            %             temp=sprintf('%f\n\n',app.VibCool.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'Result_files_directory\n');
            %             temp=sprintf('%s\n\n',app.WorkingDirectory.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'dSdT_switch\n');
            %             temp=sprintf('%s\n\n',app.dtSwitch.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'dSdrho_switch\n');
            %             temp=sprintf('%s\n\n',app.drhoSwitch.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             %%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%%
            %
            %             fprintf(ID_file,'sample_detector_distance\n');
            %             temp=sprintf('%f\n\n',app.SampleDistance.Value);
            %             %temp=sprintf('%s\n\n',app.SampleDistance.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'cage_switch\n');
            %             temp=sprintf('%s\n\n',app.CageMethodknob.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fprintf(ID_file,'cage_name\n');
            %             temp=sprintf('%s\n\n',app.CageFileEditField.Value);
            %             fprintf(ID_file,'%s',temp);
            %
            %             fclose(ID_file);
            
            if app.config.vervose==1
                dirinfo=dir(NameFile);
                temp=sprintf('Saving path : %s/%s',dirinfo.folder,dirinfo.name);
                msgbox({'Configuration file saved successfully' temp},'Scube')
            end
            
        end
        
        function SetConfig(app)
            app.config.path_gro=[];
            app.config.path_pro=[];
            
            app.config.Solvent=app.Solvent.Value;
            app.config.path_gro=app.FileGro.Value;
            app.config.path_pro=app.FilePro.Value;
            app.config.conc=app.Conc.Value;
            app.config.exct=app.Exct.Value;
            app.config.wave=app.Wave.Value;
            app.config.hour=app.Hour.Value;
            app.config.repe=app.PulseRepetition.Value;
            app.config.para=app.DutyCycle.Value;
            app.config.accul=1;
            app.config.noise=app.NoiseFileEditField.Value;
            app.config.fraction_gro_char=app.FractionGro.Value;
            app.config.fraction_pro_char=app.FractionPro.Value;
            app.config.vibcool=app.VibCool.Value;
        end
        
        function GetSolventInfo(app)
            switch app.config.Solvent
                case 'Custom'
                    
                    work_dir_temp=app.WorkingDirectory.Value;
                    temp=sprintf('%s/custom_solvent.txt',work_dir_temp);
                    solvent_data=dlmread(temp);
                    temp=sprintf('%s/custom_solvent_hydrodynamics.txt',work_dir_temp);
                    hydrodynamics_data=dlmread(temp);
                    
                    app.config.mw=solvent_data(1);
                    app.config.dens=solvent_data(2);
                    app.config.speed_of_sound=solvent_data(3);
                    app.config.c_v=solvent_data(4);
                    app.config.c_p=solvent_data(5);
                    app.config.chi_t =solvent_data(6);
                    app.config.alpha_p =solvent_data(7);
                    solvent_element_temp=solvent_data(8:end);
                    numoftext=length(solvent_element_temp)/2;
                    app.config.solvent_element=reshape(solvent_element_temp,2,numoftext/2);
                    
                    app.config.q_dt=hydrodynamics_data(:,1);
                    app.config.dt=hydrodynamics_data(:,2);
                    app.config.drho=hydrodynamics_data(:,3);
                    
                    
                case  'Acetonitrile(CH3CN)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=41.05e-3; % Kg/mol-1
                    app.config.dens=0.786e3; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1278.0; % m/s
                    app.config.c_v=63.53; % J/mol/K
                    app.config.c_p=90.00; % J/mol/K
                    app.config.chi_t =11.54e-10; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =1.3713e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('mecn_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=mecn.static(:,2);
                    %app.config.q_static=mecn.static(:,1);
                    
                    app.config.q_dt=mecn.dt(:,1);
                    app.config.dt=mecn.dt(:,2);
                    app.config.drho=mecn.drho(:,2);
                    
                    app.config.solvent_element=[1,3;6,2;7,1];
                    
                    %% Get PAL-XFEL results.
                    %                     load('mecn_palxfel');
                    %
                    %                     app.config.q=mecn_palxfel.q;
                    %                     app.config.mean=mecn_palxfel.mean;
                    %                     app.config.std=mecn_palxfel.std;
                    %                     app.config.photonflux=mecn_palxfel.photon_flux;
                    
                    %disp(app.config) %Debug
                    
                case 'Cyclohexane(C6H12)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=0.0842; % Kg/mol-1
                    app.config.dens=779.600; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1.2785e3; % m/s
                    app.config.c_v=113.60; % J/mol/K
                    app.config.c_p=156.0; % J/mol/K
                    app.config.chi_t =8.2490e-10; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =0.0012; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('cyclohexane_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=cc.static(:,2);
                    %app.config.q_static=cc.static(:,1);
                    
                    app.config.q_dt=cc.dt(:,1);
                    app.config.dt=cc.dt(:,2);
                    app.config.drho=cc.drho(:,2);
                    
                    app.config.solvent_element=[1,12;6,6];
                    
                    %% Get PAL-XFEL results.
                    %load('cc_palxfel_scaling');
                    %                     load('cyclohexane_esrf_1702_exptime1p5s_scaling');
                    %
                    %                     %app.config.q=cc_palxfel.q;
                    %                     %app.config.mean=cc_palxfel.mean;
                    %                     %app.config.std=cc_palxfel.std;
                    %                     %app.config.photonflux=cc_palxfel.photon_flux;
                    %
                    %                     app.config.q=cyclohexane_esrf_1702_exptime1p5s.q;
                    %                     app.config.mean=cyclohexane_esrf_1702_exptime1p5s.mean;
                    %                     app.config.std=cyclohexane_esrf_1702_exptime1p5s.std;
                    %                     app.config.photonflux=cyclohexane_esrf_1702_exptime1p5s.photon_flux;
                    
                case 'Water(H2O)'
                    
                    %% Get physical parameter of solvent.
                    
                    app.config.mw=18.01528e-3; % Kg/mol-1
                    app.config.dens=997.0474; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1481; % m/s
                    app.config.c_v=74.53;%113.60; % J/mol/K
                    app.config.c_p=75.327;%156.0; % J/mol/K
                    app.config.chi_t =4.6e-10; % Pa-1, (N/m^2)-1, isothermal compression coefficient
                    app.config.alpha_p =0.207e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('h2o_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %app.config.static=h2o.static(:,2);
                    %app.config.q_static=h2o.static(:,1);
                    
                    app.config.q_dt=h2o.dt(:,1);
                    app.config.dt=h2o.dt(:,2);
                    app.config.drho=h2o.drho(:,2);
                    
                    app.config.solvent_element=[1,2;8,1];
                    
                    %                     %% Get PAL-XFEL results.
                    %                     load('h2o_palxfel_scaling');
                    %
                    %                     app.config.q=h2o_palxfel.q;
                    %                     app.config.mean=h2o_palxfel.mean;
                    %                     app.config.std=h2o_palxfel.std;
                    %                     app.config.photonflux=h2o_palxfel.photon_flux;
                    
                case 'Methanol(CH3OH)'
                    
                    app.config.mw=0.0842; % Kg/mol-1
                    app.config.dens=791.0; % Kg/m3 All Mw, dens From Wiki
                    app.config.speed_of_sound=1143.0; % m/s
                    app.config.c_v=69.50; % J/mol/K
                    app.config.c_p=81.10; % J/mol/K
                    app.config.chi_t =1.214e-9; % Pa-1, (N/m^2)-1, iso;thermal compression coefficient
                    app.config.alpha_p =1.49e-3; % k /K, isobaric thermal expansion coefficient
                    
                    %% Get MD results.
                    load('meoh_data'); % mecn.static(q by 2), mecn.dt(q by 2), mecn.drho (q by 2)
                    
                    %                     app.config.static=meoh.static(:,2);
                    %                     app.config.q_static=meoh.static(:,1);
                    
                    app.config.q_dt=meoh.dt(:,1);
                    app.config.dt=meoh.dt(:,2);
                    app.config.drho=meoh.drho(:,2);
                    
                    app.config.solvent_element=[1,4;6,1;8,1];
                    
                    %                     %% Get PAL-XFEL results.
                    %                     load('meoh_palxfel_scaling');
                    %
                    %                     app.config.q=meoh_palxfel.q;
                    %                     app.config.mean=meoh_palxfel.mean;
                    %                     app.config.std=meoh_palxfel.std;
                    %                     app.config.photonflux=meoh_palxfel.photon_flux;
                    %
                    
                otherwise
                    disp('Error!!!!!')
                    disp('The solvent is a non-listed solvent')
                    disp('list: Acetonitrile, Cyclohexane, Methanol, Water')
            end
        end
        
        
        function ReadCoord(app)
            
            app.config.numgro=length(app.FileGro.Value);
            app.config.numpro=length(app.FilePro.Value);
            
            app.config.coord_gro=cell(app.config.numgro);
            app.config.coord_pro=cell(app.config.numpro);
            
            for ii=1:app.config.numgro
                try
                    app.config.coord_gro{ii}=importdata(app.config.path_gro{ii});
                catch
                    error('Coordination files error.');
                end
                app.config.coord_gro{ii}.num=GetNumFromSym(app,app.config.coord_gro{ii}.textdata);
            end
            
            for ii=1:app.config.numpro
                try
                    app.config.coord_pro{ii}=importdata(app.config.path_pro{ii});
                catch
                    error('Coordination files error.');
                end
                app.config.coord_pro{ii}.num=GetNumFromSym(app,app.config.coord_pro{ii}.textdata);
            end
            
            %%Debug
            %disp(app.config);
            %disp(app.config.coord_gro);
            %disp(app.config.coord_pro);
        end
        
        function Results=GetDebye(app,coord,q)
            NumQ=length(q);
            [NumAtom,~]=size(coord.data);
            x=coord.data(:,1);
            y=coord.data(:,2);
            z=coord.data(:,3);
            atom=coord.num(:);
            
            Termfirst=zeros(NumQ,1);
            for i=1:NumAtom
                Termfirst=Termfirst+app.cal.form_factor_table(:,atom(i)).^2;
            end
            
            Termsecond=zeros(NumQ,1);
            for i=1:NumAtom-1
                for j=i+1:NumAtom
                    r=sqrt((x(i)-x(j))^2+(y(i)-y(j))^2+(z(i)-z(j))^2);
                    for n=1:NumQ
                        Termsecond(n)=Termsecond(n)+app.cal.form_factor_table(n,atom(i))*app.cal.form_factor_table(n,atom(j))*sin(q(n)*r)/(q(n)*r);
                    end
                end
            end
            Results=Termfirst+2*Termsecond;
        end
        
        function CalNoise(app)
            app.config.photonflux_ref=app.config.pulse_repetition_ref*app.config.exposure_time_ref*app.config.photons_per_pulse_ref;
            app.config.photonflux=app.PulseRepetition.Value*app.ExposureTime.Value*app.PhotonsPerPulse.Value;
            app.config.photonflux_scaling=app.config.photonflux/app.config.photonflux_ref; % Flux scaling factor
            
            app.config.numofimage=round(app.config.hour*3600*app.config.para/2/app.ExposureTime.Value); % total image number
            
            NumQ=length(app.config.q);
            
            app.config.stdscaling=sqrt(app.config.photonflux_scaling);
            
            %disp(app.config.stdscaling)
            %             app.config.stdscaling_gro=sqrt(app.config.photonflux_scaling*abs(app.config.mean+app.config.debye_gro)./abs(app.config.mean));
            %             app.config.stdscaling_gro=app.config.photonflux_scaling;
            %             app.config.stdscaling_pro=sqrt(app.config.photonflux_scaling*abs(app.config.mean+app.config.exct*app.config.debye_pro+(1-app.config.exct)*app.config.debye_gro)./abs(app.config.mean));
            %             app.config.stdscaling_pro=app.config.photonflux_scaling;
            
            %%% Get mean
            %             I_std_species_total=zeros(NumQ,app.config.numofimage);% Temp remove
            
            I_std_species1=zeros(NumQ,1);
            I_std_species2=zeros(NumQ,1);
            for ii=1:app.config.numofimage
                if ii==1
                    fprintf('  Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                elseif ii==app.config.numofimage
                    fprintf('  Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                elseif 0==rem(ii,1000)
                    fprintf('  Current simulation image : %d/%d (%.0f%%)\n',ii,app.config.numofimage,100*ii/app.config.numofimage);
                else
                end
                random_noise1 = randn(NumQ,1);
                random_noise2 = randn(NumQ,1);
                sum_temp1=app.config.std.*random_noise1./app.config.stdscaling;
                sum_temp2=app.config.std.*random_noise2./app.config.stdscaling;
                I_std_species1=I_std_species1+sum_temp1;
                I_std_species2=I_std_species2+sum_temp2;
                %                 I_std_species_total(:,ii)=sum_temp1-sum_temp2; % Temp remove
            end
            %             app.results.std=std(I_std_species_total,0,2)*app.config.scaling_factor; %% Temp_remove plz
            app.results.err=(I_std_species2-I_std_species1)/(app.config.numofimage)*app.config.scaling_factor;
        end
        
        function MergeSimulationResults(app)
            
            %             q=app.config.q;
            solute=app.results.solute;
            dsdt=app.results.dsdt;
            dsdrho=app.results.dsdrho;
            err=app.results.err;
            cage=app.results.cage;
            
            app.results.sum=solute+dsdt+dsdrho+err+cage;
            app.results.sumwoerr=app.results.sum-app.results.err;
            
        end
        
        function WriteOutput(app,time,path)

            
            ResultsFolder=sprintf('%s/Scube_results_%s',path,time);
            
            app.config.vervose=0;
            WriteConfig(app,time,ResultsFolder)
            [~,~]=mkdir(ResultsFolder);
            
            FileName=sprintf('%s/Scube_results_%s.txt',ResultsFolder,time);
            FileID=fopen(FileName,'w');
            fprintf(FileID,'#q\t#%s\t#Simulation_without_noise\t#Solute\t#Solvent_heating_temperatue\t#Solvent_heating_density\t#Cage','Simulation_with_noise');
            for ii=1:length(app.config.q)
                fprintf(FileID,'\n %-12.6g \t %-12.6g \t %-12.6g \t%-12.6g \t%-12.6g \t%-12.6g \t%-12.6g',app.config.q(ii),app.results.sum(ii),app.results.sumwoerr(ii),app.results.solute(ii),app.results.dsdt(ii),app.results.dsdrho(ii),app.results.cage(ii));
            end
            fclose(FileID);
            
           FileName=sprintf('%s/Scube_results_qSq_%s.txt',ResultsFolder,time);
            FileID=fopen(FileName,'w');
            fprintf(FileID,'#q\t#%s\t#Simulation_without_noise\t#Solute\t#Solvent_heating_temperatue\t#Solvent_heating_density\t#Cage','Simulation_with_noise');
            for ii=1:length(app.config.q)
                fprintf(FileID,'\n %-12.6g \t %-12.6g \t %-12.6g \t%-12.6g \t%-12.6g \t%-12.6g \t%-12.6g',app.config.q(ii),app.config.q(ii).*app.results.sum(ii),app.config.q(ii).*app.results.sumwoerr(ii),app.config.q(ii).*app.results.solute(ii),app.config.q(ii).*app.results.dsdt(ii),app.config.q(ii).*app.results.dsdrho(ii),app.config.q(ii).*app.results.cage(ii));
            end
            fclose(FileID);
        end
        
        function DrawScubeResults(app)
            figure
            set(gcf,'numbertitle','off','name', 'S-cube results');
            plot(app.config.q,[app.config.q.*app.results.sum,app.config.q.*app.results.sumwoerr,app.config.q.*app.results.solute,app.config.q.*app.results.dsdt,app.config.q.*app.results.dsdrho,app.config.q.*app.results.cage]);
            title('Simulation results');
            xlabel('q');
            ylabel('qS(q)');
            legend(["Simulation(with noise)","Simulation(w/o noise)","Solute","Solvent heating(temperaute)","Solvent heating(density)","Cage"]);
        end
        
        
        function MakeFormFactorTable(app)
            NumQ=length(app.config.q);
            NumAtom=104;
            
            form_factor_table=zeros(NumQ,NumAtom);
            for ii=1:NumQ % ii : temp_q
                for jj=1:NumAtom % jj : temp_atomic_number
                    form_factor_table(ii,jj)=form_factor(app,jj,app.config.q(ii));
                end
            end
            app.cal.form_factor_table=form_factor_table;
        end
        
        
        function ReadFraction(app)
            %% Products
            command_fraction_read=sprintf('app.config.fraction_gro=[%s];',app.config.fraction_gro_char);
            eval(command_fraction_read);
            %% Reactants
            command_fraction_read=sprintf('app.config.fraction_pro=[%s];',app.config.fraction_pro_char);
            eval(command_fraction_read);
            %% Debug
            %             disp(app.config.fraction_gro)
            %             disp(app.config.fraction_pro)
        end
        
        
        function gr = gr_convert(~,r,VDR,R)
            numofr=length(r);
            
            bool_0=r<=VDR;
            num_0=sum(bool_0);
            
            
            bool_1=r>=R+VDR;
            bool_cal=VDR<r&r<R+VDR;
            num_cal=sum(bool_cal);
            dy=1/(num_cal+1);
            
            gr=zeros(1,numofr);
            for ii=1:num_cal
                gr(num_0+ii)=ii*dy;
            end
            gr=gr+bool_1.*ones(1,numofr);
        end
        
        function num=sym2num_pair(app,text)
            posi=strfind(text,'_');
            if 1==isempty(posi)
                textdata=text;
            else
                textdata=text(1:posi-1);
            end
            for i=1:104
                if strcmp(textdata,app.constant.elements{i})==1
                    num=i;
                else
                end
            end
        end
        
        function [pair,Z,N,PDF_cutoff]=AnalyzePair(app,pair_cell,atom)
            numofpair=length(pair_cell);
            pair=cell(2,numofpair);
            Z=nan(2,numofpair);
            N=nan(2,numofpair);
            PDF_cutoff=nan(1,numofpair);
            for ii=1:numofpair
                posi1=strfind(pair_cell{1,ii},'-');
                pair{1,ii}=pair_cell{1,ii}(1:posi1-1);
                pair{2,ii}=pair_cell{1,ii}(posi1+1:end);
                for jj=1:2
                    Z(jj,ii)=sym2num_pair(app,pair{jj,ii});
                    N(jj,ii)=atom_count(app,pair{jj,ii},atom);
                end
                PDF_cutoff(ii)=app.constant.vanderwaals(Z(1,ii))+app.constant.vanderwaals(Z(2,ii));
            end
        end
        
        function SensitivityAtomicPosition(app)
            app.config.vervose=0;
            configOrg=app.config;
            q=app.config.q;
            
            SoluteRef=app.results.solute;
            SolventRef=app.results.dsdt+app.results.dsdrho;
            CageRef=app.results.cage;
            
            DSref=SoluteRef+SolventRef+CageRef;
            
            % Get Spiral Info
            SpiralSamplingNumber = app.SpiralSamplingNumberEditField.Value;
            SpiralRadius = app.SpiralRadiusEditField.Value;
            
            SpiralCoord = GetSpiralCoord(SpiralSamplingNumber,SpiralRadius);
            
            %%%%%% Gro Coord atomic position sensitivity Calculation %%%%%%
            
            
            for CoordIndex=1:app.config.numgro
                if CoordIndex==1
                    fprintf('  Start atomic position sensitivity calculation of %dst reactant coordination\n',CoordIndex);
                elseif CoordIndex==2
                    fprintf('  Start atomic position sensitivity calculation of %dnd reactant coordination\n',CoordIndex);
                elseif CoordIndex==3
                    fprintf('  Start atomic position sensitivity calculation of %drd reactant coordination\n',CoordIndex);
                else
                    fprintf('  Start atomic position sensitivity calculation of %dth reactant coordination\n',CoordIndex);
                end
                numAtom=size(app.config.coord_gro{CoordIndex}.data,1);
                app.sensitivity.AtomicPosition.gro{CoordIndex}=zeros(numAtom,1);
                for AtomIndex=1:numAtom
                    qDZall=zeros(length(q),1);
                    for SamplingIndex1=1:SpiralSamplingNumber
                        app.config=configOrg;
                        app.config.coord_gro{CoordIndex}.data(AtomIndex,:)=app.config.coord_gro{CoordIndex}.data(AtomIndex,:)+SpiralCoord(SamplingIndex1,:);
                        
                        CalSolute(app);
                        CalCage(app);
                        
                        SoluteSignal=app.results.solute;
                        SolventSignal=SolventRef;
                        CageSignal=app.results.cage;
                        
                        DS=SoluteSignal+SolventSignal+CageSignal;
                        
                        qDZall=qDZall+(DS-DSref).^2;
                    end
                    qDZ=q.*sqrt(qDZall/(SpiralSamplingNumber-1));
                    app.sensitivity.AtomicPosition.gro{CoordIndex}(AtomIndex)=sum(qDZ);
                end
            end
            
            %%%%%% Pro Coord atomic position sensitivity Calculation %%%%%%
            
            for CoordIndex=1:app.config.numpro
                if CoordIndex==1
                    fprintf('  Start atomic position sensitivity calculation of %dst product/intermediate coordination\n',CoordIndex);
                elseif CoordIndex==2
                    fprintf('  Start atomic position sensitivity calculation of %dnd product/intermediate coordination\n',CoordIndex);
                elseif CoordIndex==3
                    fprintf('  Start atomic position sensitivity calculation of %drd product/intermediate coordination\n',CoordIndex);
                else
                    fprintf('  Start atomic position sensitivity calculation of %dth product/intermediate coordination\n',CoordIndex);
                end
                numAtom=size(app.config.coord_pro{CoordIndex}.data,1);
                app.sensitivity.AtomicPosition.pro{CoordIndex}=zeros(numAtom,1);
                for AtomIndex=1:numAtom
                    qDZall=zeros(length(q),1);
                    for SamplingIndex1=1:SpiralSamplingNumber
                        app.config=configOrg;
                        app.config.coord_pro{CoordIndex}.data(AtomIndex,:)=app.config.coord_pro{CoordIndex}.data(AtomIndex,:)+SpiralCoord(SamplingIndex1,:);
                        
                        CalSolute(app);
                        CalCage(app);
                        
                        SoluteSignal=app.results.solute;
                        SolventSignal=SolventRef;
                        CageSignal=app.results.cage;
                        
                        DS=SoluteSignal+SolventSignal+CageSignal;
                        
                        qDZall=qDZall+(DS-DSref).^2;
                    end
                    qDZ=q.*sqrt(qDZall/(SpiralSamplingNumber-1));
                    app.sensitivity.AtomicPosition.pro{CoordIndex}(AtomIndex)=sum(qDZ);
                end
            end
            
            app.config=configOrg;
            
            
            ResultsFolder=sprintf('%s/AtomicPosion_Sensitivity_results_%s',app.WorkingDirectory.Value,app.config.time);
            [~,~]=mkdir(ResultsFolder);
            app.config.vervose=0;
            WriteConfig(app,app.config.time,ResultsFolder);

            for CoordIndex=1:app.config.numgro
                [~,fileName,~]=fileparts(app.config.path_gro{CoordIndex});
                ResultsName=sprintf('%s/%s_AtomicPosition_Sensitivity_%s.dat',ResultsFolder,fileName,app.config.time);
                writematrix(app.sensitivity.AtomicPosition.gro{CoordIndex},ResultsName,'Delimiter','tab');
            end
            
            for CoordIndex=1:app.config.numpro
                [~,fileName,~]=fileparts(app.config.path_pro{CoordIndex});
                ResultsName=sprintf('%s/%s_AtomicPosition_Sensitivity_%s.dat',ResultsFolder,fileName,app.config.time);
                writematrix(app.sensitivity.AtomicPosition.pro{CoordIndex},ResultsName,'Delimiter','tab');
            end
            
            
            function SpiralCoord = GetSpiralCoord(SpiralSamplingNumber,SpiralRadius)
                % Allocate spiral coordination
                ThetaSpiral=zeros(SpiralSamplingNumber,1);
                PiSpiral=zeros(SpiralSamplingNumber,1);
                
                for SamplingIndex=1:SpiralSamplingNumber
                    ThetaSpiral(SamplingIndex)=acos((2*SamplingIndex-1-SpiralSamplingNumber)/SpiralSamplingNumber);
                    PiSpiral(SamplingIndex)=sqrt(pi*SpiralSamplingNumber)*asin((2*SamplingIndex-1-SpiralSamplingNumber)/SpiralSamplingNumber);
                end
                SpiralRaduses=SpiralRadius*ones(SpiralSamplingNumber,1);
                
                %SphereCoord=[SpiralRaduses ThetaSpiral PiSpiral];
                
                x=SpiralRaduses.*sin(ThetaSpiral).*cos(PiSpiral);
                y=SpiralRaduses.*sin(ThetaSpiral).*sin(PiSpiral);
                z=SpiralRaduses.*cos(ThetaSpiral);
                
                SpiralCoord=[x y z];
            end
        end
        
        function SensitivityInternuclearDistance(app)
            NuclearDistance=app.ChangingNuclearDistanceEditField.Value;
            SoluteRef=app.results.solute;
            
            for TargetCoordIndex=1:app.config.numgro
                if TargetCoordIndex==1
                    fprintf('  Start Internuclear distance sensitivity calculation of %dst reactant coordination\n',TargetCoordIndex);
                elseif TargetCoordIndex==2
                    fprintf('  Start Internuclear distance sensitivity calculation of %dnd reactant coordination\n',TargetCoordIndex);
                elseif TargetCoordIndex==3
                    fprintf('  Start Internuclear distance sensitivity calculation of %drd reactant coordination\n',TargetCoordIndex);
                else
                    fprintf('  Start Internuclear distance sensitivity calculation of %dth reactant coordination\n',TargetCoordIndex);
                end
                app.sensitivity.internuclear.gro{TargetCoordIndex}=zeros(1,3);
                IsGro=1;
                numAtom=size(app.config.coord_gro{TargetCoordIndex}.data,1);
                app.sensitivity.InternuclearDistance.gro{TargetCoordIndex}=zeros(numAtom,1);
                numBond=1;
                for atom1=1:numAtom-1
                    for atom2=atom1+1:numAtom
                        %                         tic
                        chi=0;
                        for k=1:2
                            switch k
                                case 1
                                    ChangingDistance=NuclearDistance;
                                case 2
                                    ChangingDistance=-NuclearDistance;
                            end
%                             tic
                            SoluteSignal=CalSoluteInternuculearDistance(app,TargetCoordIndex,IsGro,ChangingDistance,atom1,atom2);
%                             toc
                            chi=chi+(SoluteRef-SoluteSignal).^2;
                        end
                        qDZ=app.config.q.*sqrt(chi);
                        %                         toc
                        
                        app.sensitivity.internuclear.gro{TargetCoordIndex}(numBond,1)=atom1;
                        app.sensitivity.internuclear.gro{TargetCoordIndex}(numBond,2)=atom2;
                        app.sensitivity.internuclear.gro{TargetCoordIndex}(numBond,3)=sum(qDZ);
                        numBond=numBond+1;
                    end
                end
            end
            
            for TargetCoordIndex=1:app.config.numpro
                if TargetCoordIndex==1
                    fprintf('  Start Internuclear distance sensitivity calculation of %dst product/intermediate coordination\n',TargetCoordIndex);
                elseif TargetCoordIndex==2
                    fprintf('  Start Internuclear distance sensitivity calculation of %dnd product/intermediate coordination\n',TargetCoordIndex);
                elseif TargetCoordIndex==3
                    fprintf('  Start Internuclear distance sensitivity calculation of %drd product/intermediate coordination\n',TargetCoordIndex);
                else
                    fprintf('  Start Internuclear distance sensitivity calculation of %dth product/intermediate coordination\n',TargetCoordIndex);
                end
                app.sensitivity.internuclear.pro{TargetCoordIndex}=zeros(1,3);
                IsGro=0;
                numAtom=size(app.config.coord_pro{TargetCoordIndex}.data,1);
                app.sensitivity.InternuclearDistance.pro{TargetCoordIndex}=zeros(numAtom,1);
                numBond=1;
                for atom1=1:numAtom-1
                    for atom2=atom1+1:numAtom
                        chi=0;
                        for ChangingDistance=[NuclearDistance, -NuclearDistance]
                            SoluteSignal=CalSoluteInternuculearDistance(app,TargetCoordIndex,IsGro,ChangingDistance,atom1,atom2);
                            chi=chi+(SoluteRef-SoluteSignal).^2;
                        end
                        qDZ=app.config.q.*sqrt(chi);
                        
                        app.sensitivity.internuclear.pro{TargetCoordIndex}(numBond,1)=atom1;
                        app.sensitivity.internuclear.pro{TargetCoordIndex}(numBond,2)=atom2;
                        app.sensitivity.internuclear.pro{TargetCoordIndex}(numBond,3)=sum(qDZ);
                        numBond=numBond+1;
                    end
                end
            end
            
            ResultsFolder=sprintf('%s/Internuclear_Sensitivity_results_%s',app.WorkingDirectory.Value,app.config.time);
            [~,~]=mkdir(ResultsFolder);
            app.config.vervose=0;
            WriteConfig(app,app.config.time,ResultsFolder);
            
            for CoordIndex=1:app.config.numgro
                [~,fileName,~]=fileparts(app.config.path_gro{CoordIndex});
                ResultsName=sprintf('%s/%s_Internuclear_Sensitivity_%s.dat',ResultsFolder,fileName,app.config.time);
                writematrix(app.sensitivity.internuclear.gro{CoordIndex},ResultsName,'Delimiter','tab');
            end
            
            for CoordIndex=1:app.config.numpro
                [~,fileName,~]=fileparts(app.config.path_pro{CoordIndex});
                ResultsName=sprintf('%s/%s_Internuclear_Sensitivity_%s.dat',ResultsFolder,fileName,app.config.time);
                writematrix(app.sensitivity.internuclear.pro{CoordIndex},ResultsName,'Delimiter','tab');
            end


            function SoluteSignal=CalSoluteInternuculearDistance(app,TargetCoordIndex,IsGro,ChangingDistance,atom1,atom2)
                MakeFormFactorTable(app);
                q=app.config.q;
                sumTemp=zeros(length(q),1);
                for coordIndex=1:app.config.numgro
                    %                     tic
                    if coordIndex==TargetCoordIndex && IsGro==1
                        DebyeCurve=GetDebyeChangeDistance(app,app.config.coord_gro{coordIndex},ChangingDistance,atom1,atom2)*app.config.fraction_gro(coordIndex);
                    else
                        DebyeCurve=app.config.coord_gro{coordIndex}.debye;
                    end
                    %                     toc
                    sumTemp=sumTemp+DebyeCurve;
                end
                NegativeSolute=sumTemp*app.config.exct/app.config.R;
                
                sumTemp=zeros(length(q),1);
                for coordIndex=1:app.config.numpro
                    if coordIndex==TargetCoordIndex && IsGro==0
                        DebyeCurve=GetDebyeChangeDistance(app,app.config.coord_pro{coordIndex},ChangingDistance,atom1,atom2)*app.config.fraction_pro(coordIndex);
                    else
                        DebyeCurve=app.config.coord_pro{coordIndex}.debye;
                    end
                    sumTemp=sumTemp+DebyeCurve;
                end
                PositiveSolute=sumTemp*app.config.exct/app.config.R;
                
                SoluteSignal=PositiveSolute-NegativeSolute;
                
                function Results=GetDebyeChangeDistance(app,coord,ChangingDistance,atom1,atom2)
                    q=app.config.q;
                    NumQ=length(q);
                    [NumAtom,~]=size(coord.data);
                    x=coord.data(:,1);
                    y=coord.data(:,2);
                    z=coord.data(:,3);
                    atom=coord.num(:);
                    
                    Termfirst=zeros(NumQ,1);
                    for i=1:NumAtom
                        Termfirst=Termfirst+app.cal.form_factor_table(:,atom(i)).^2;
                    end
                    
                    Termsecond=zeros(NumQ,1);
                    for i=1:NumAtom-1
                        for j=i+1:NumAtom
                            r=sqrt((x(i)-x(j))^2+(y(i)-y(j))^2+(z(i)-z(j))^2);
                            if atom1==i && atom2==j
                                r=r+ChangingDistance;
                            end
                            for n=1:NumQ
                                Termsecond(n)=Termsecond(n)+app.cal.form_factor_table(n,atom(i))*app.cal.form_factor_table(n,atom(j))*sin(q(n)*r)/(q(n)*r);
                            end
                        end
                    end
                    Results=Termfirst+2*Termsecond;
                end
            end
            
            
        end
    end
    
    methods (Access = public)
        
        function ReadConstant(app)
            
            load('constant')
            
            app.constant=constant; %#ok<ADPROP>
            
            app.config.cvcp=app.config.c_v/app.config.c_p;
            app.config.sigma=(app.config.c_v-app.config.c_p)*app.config.chi_t/app.config.alpha_p;
            app.config.R=app.config.dens/app.config.mw/app.config.conc;
            app.config.conc_solv=app.config.dens/app.config.mw;
            
            
        end
        
        function num=GetNumFromSym(app,textdata)
            NumAtom=length(textdata);
            num=nan(NumAtom,1);
            for atom=1:NumAtom % change atom to element number
                for i=1:104
                    if strcmp(textdata{atom},app.constant.elements{i})==1
                        num(atom)=i;
                    else
                    end
                end
            end
        end
        
        function CalSolute(app)
            MakeFormFactorTable(app);
            sum_temp=zeros(length(app.config.q),1);
            for ii=1:app.config.numgro
                if ii==1 && app.config.vervose==1
                    fprintf('  Start debye calculation of %dst ground state coordination\n',ii);
                elseif ii==2 && app.config.vervose==1
                    fprintf('  Start debye calculation of %dnd ground state coordination\n',ii);
                elseif ii==3 && app.config.vervose==1
                    fprintf('  Start debye calculation of %drd ground state coordination\n',ii);
                elseif app.config.vervose==1
                    fprintf('  Start debye calculation of %dth ground state coordination\n',ii);
                else
                end
                %                 tic
                app.config.coord_gro{ii}.debye=GetDebye(app,app.config.coord_gro{ii},app.config.q)*app.config.fraction_gro(ii);
                sum_temp=sum_temp+app.config.coord_gro{ii}.debye;
                %                 toc
            end
            app.config.debye_gro=sum_temp*app.config.exct/app.config.R;
            sum_temp=zeros(length(app.config.q),1);
            for ii=1:app.config.numpro
                if ii==1 && app.config.vervose==1
                    fprintf('  Start debye calculation of %dst intermediate state/product coordination\n',ii);
                elseif ii==2 && app.config.vervose==1
                    fprintf('  Start debye calculation of %dnd intermediate state/product coordination\n',ii);
                elseif ii==3 && app.config.vervose==1
                    fprintf('  Start debye calculation of %drd intermediate state/product coordination\n',ii);
                elseif app.config.vervose==1
                    fprintf('  Start debye calculation of %dth intermediate state/product coordination\n',ii);
                else
                end
                %                 tic
                app.config.coord_pro{ii}.debye=GetDebye(app,app.config.coord_pro{ii},app.config.q)*app.config.fraction_pro(ii);
                sum_temp=sum_temp+app.config.coord_pro{ii}.debye;
                %                 toc
            end
            app.config.debye_pro=sum_temp*app.config.exct/app.config.R;
            
            app.config.solute=app.config.debye_pro-app.config.debye_gro;
            app.results.solute=app.config.solute;
            
            %             numofstate=2;
            %             temp=app.config.coord2state(app.config.coord);
            %             for ii=1:numofstate
            %                 app.config.state{ii}.debye=temp(:,ii)/app.config.R;
            %             end
            
            
            %%Debug
            %figure
            %plot(app.config.q,app.config.solute);
            
        end
        
        function form=form_factor(app,atomic_number,q)
            s=q/4/pi;
            %% Simple calculate for An,Bn,Cn term (D. Waasmaier and A. Kirfel)
            temp=0;
            for k=1:5
                temp=temp+app.constant.Xfactor(atomic_number,k)*exp(-app.constant.Xfactor(atomic_number,k+6)*s*s);
            end
            form=temp+app.constant.Xfactor(atomic_number,6);
        end
        
        function CalSolvent(app)
            Getdtdrho(app)
            app.config.dsdt_interp=interp1(app.config.q_dt,app.config.dt,app.config.q,'spline');
            app.config.dsdrho_interp=interp1(app.config.q_dt,app.config.drho,app.config.q,'spline');
            
            %             app.config.dsdt_final=app.config.dT_max*app.config.dsdt_interp;
            %             app.config.dsdrho_final=app.config.drho_max*app.config.dsdrho_interp;
            %
            app.results.dsdt=app.config.dT_max*app.config.dsdt_interp;
            app.results.dsdrho=app.config.drho_max*app.config.dsdrho_interp;
            
            
            %             figure
            %             plot(app.config.q,[app.config.dsdt_final,app.config.dsdrho_final]);
        end
        
        function Getdtdrho(app)
            %% Q(t)
            Qt=app.constant.Na/app.config.R*app.constant.ha*app.constant.speed_l/(app.config.wave*1e-9)*(app.config.exct+app.config.vibcool);
            %             Qt=app.constant.Na/app.config.R*app.constant.ha*app.constant.speed_l/(app.config.wave*1e-9);
            %% Delta T(t) Max
            if strcmp(app.dtSwitch.Value,'On')
                app.config.dT_max = (Qt/app.config.c_v);
            elseif strcmp(app.dtSwitch.Value,'Off')
                app.config.dT_max=0;
            else
                disp('Error!! Wrong dt_switch name')
            end
            dT_temp=(Qt/app.config.c_p);
            %% Delta rho(t) Max
            if strcmp(app.drhoSwitch.Value,'On')
                app.config.drho_max = app.config.dens*(-app.config.alpha_p*dT_temp);
            elseif strcmp(app.drhoSwitch.Value,'Off')
                app.config.drho_max=0;
            else
                disp('Error!! Wrong drho_switch name')
            end
        end
        
        %         function axout = molecule3D(varargin)
        %             RC = 0.2; % radius of bonds
        %             DBOND = 1.6; % maximal distance of atoms forming bond
        %
        %             % resolution of spheres and cylinders
        %             NS = 50; % spheres, more looks smoother
        %             NB = 50; % more looks smoother
        %
        %             % check input arguments
        %             narginchk(3,inf);
        %
        %             if numel(varargin{2}) == 1 && ishghandle(varargin{2}(1)) && ...
        %                     isequal(lower(char(get(varargin{2}(1),'type'))),'axes')
        %                 ax = varargin{2};
        %                 offs = 1;
        %             else
        %                 ax = [];
        %                 offs = 0;
        %             end
        %
        %             xyz = varargin{2+offs};
        %             Na = size(xyz,1); % number of atoms in molecule
        %
        %             lab = varargin{3+offs};
        %             if ~iscellstr(lab)
        %                 error('Input LAB has to be a cell string')
        %             end
        %             lab = lab(:); % convert to column vector
        %
        %             if nargin - offs < 4
        %                 style = 'ballstick'; % set default style to rad/stick
        %             else
        %                 style = varargin{3+offs};
        %             end
        %
        %             if ~any(strcmp(style,{'ballstick','licorice','large','superlarge'}))
        %                 warning('Style "%s" not found.',style)
        %                 style = 'ballstick'; % fallback
        %             end
        %
        %             if size(xyz,2) ~= 3 || Na < 1
        %                 error('First argument should be N times 3 matrix.')
        %             end
        %
        %             if numel(lab) ~= Na
        %                 error('Number of labels does not match number of columns.')
        %             end
        %
        %             % prepare axis
        %             ax = newplot(ax);
        %             axes(ax);
        %             set(ax,'Visible','off','DataAspectRatio',[1 1 1]) % fix aspect ratio
        %
        %             light('Position',[1 1 2]); % add some light
        %
        %             % all combinations of atom pairs
        %             pairs = combnk(1:Na,2);
        %             % all interatomic distances
        %             ds = sqrt(sum((xyz(pairs(:,1),:) - xyz(pairs(:,2),:)).^2,2));
        %
        %             % find bonds based on distances of atoms
        %             ks = find(ds < DBOND & ~(strcmp(lab(pairs(:,1)),'H') & ...
        %                 strcmp(lab(pairs(:,2)),'H')))';
        %
        %             % draw sphere with adapted radius for each element / line in xyz
        %             for k = 1:size(xyz,1)
        %                 thiscol = col(app,lab{k});
        %
        %                 switch style
        %                     case 'ballstick'
        %                         thisr = rad(app,lab{k});
        %                     case 'licorice'
        %                         thisr = RC;
        %                     case 'large'
        %                         thisr = 2*rad(app,lab{k});
        %                     case 'superlarge'
        %                         thisr = 3*rad(app,lab{k});
        %                 end
        %
        %                 % basic sphere
        %                 [sx,sy,sz] = sphere(NS);
        %
        %                 % draw sphere
        %                 surface('XData',xyz(k,1) + thisr*sx,'YData',xyz(k,2) + thisr*sy, ...
        %                     'ZData',xyz(k,3) + thisr*sz,'FaceColor',thiscol, ...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %             end
        %
        %             % draw cylinders for each bond
        %             for k = ks % draw sticks for all bounds
        %                 r1 = xyz(pairs(k,1),:); % coordinates atom 1
        %                 r2 = xyz(pairs(k,2),:); % coordinates atom 2
        %
        %                 % bond angles in spherical coordinates
        %                 v = (r2-r1)/norm(r2-r1);
        %                 phi = atan2d(v(2),v(1));
        %                 theta = -asind(v(3));
        %
        %                 % bond distance minus sphere radii
        %                 bd = ds(k) - rad(app,lab{pairs(k,1)}) - rad(app,lab{pairs(k,2)});
        %                 cyl2 = rad(app,lab{pairs(k,1)}) + bd/2; % length half bond cylinder
        %                 cyl1 = ds(k); % length full bond cylinder
        %
        %                 % prototype cylinders for bond
        %                 [z,y,x] = cylinder(RC,NB); % full bond cylinder
        %                 x(2,:) = x(2,:) * cyl1; % adjust length
        %                 [z2,y2,x2] = cylinder(RC*1.01,NB); % half bond cylinder, thicker
        %                 x2(2,:) = x2(2,:) * cyl2; % adjust length
        %
        %                 % rotate cylinders to match bond vector v
        %                 for kk = 1:numel(x)
        %                     vr = [x(kk); y(kk); z(kk);];
        %                     vr = rotz_jkim(phi)*roty_jkim(theta)*vr;
        %                     x(kk) = vr(1);
        %                     y(kk) = vr(2);
        %                     z(kk) = vr(3);
        %
        %                     vr = [x2(kk); y2(kk); z2(kk);];
        %                     vr = rotz_jkim(phi)*roty_jkim(theta)*vr;
        %                     x2(kk) = vr(1);
        %                     y2(kk) = vr(2);
        %                     z2(kk) = vr(3);
        %                 end
        %
        %                 % get colors of both atoms
        %                 thiscol1 = col(app,lab{pairs(k,2)});
        %                 thiscol2 = col(app,lab{pairs(k,1)});
        %
        %                 % full bond color 1
        %                 surface('XData',r1(1) + x,'YData',r1(2) + y,...
        %                     'ZData',r1(3) + z,'FaceColor',thiscol1,...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %
        %                 % half bond color 2
        %                 surface('XData',r1(1) + x2,'YData',r1(2) + y2,...
        %                     'ZData',r1(3) + z2,'FaceColor',thiscol2,...
        %                     'EdgeColor','none','FaceLighting','gouraud')
        %             end
        %
        %             if nargout > 0
        %                 axout = ax;
        %             end
        %
        %         end
        %
        %         function c = col(app,s)
        %             switch s
        %                 case  'H', c = [1 1 1];
        %                 case  'C', c = [0.2 0.2 0.2];
        %                 case  'O', c = [1.0 0.1 0.1];
        %                 case  'N', c = [0.5 0.5 0.6];
        %                 case  'I', c = [0.4 0.1 0.7];
        %                 case  {'F','Cl'}, c = [0.2 0.9 0.2];
        %                 case  'Br', c = [0.6 0.1 0.1];
        %                 case  {'He','Ne','Ar','Kr','Xe'}, c = [0.2 1.0 1.0];
        %                 case  'P', c = [1.0 0.6 0.2];
        %                 case  'S', c = [0.9 0.9 0.2];
        %                 case  'B', c = [1.0 0.7 0.5];
        %                 case  {'Li','Na','K','Rb','Cs','Fr'}, c = [0.5 0.1 1.0];
        %                 case  {'Be','Mg','Ca','Sr','Ba','Ra'}, c = [0.1 0.5 0.1];
        %                 case  'Ti', c = [0.6 0.6 0.6];
        %                 case  'Fe', c = [0.9 0.5 0.1];
        %                 otherwise, c = [0.9 0.5 1.0];
        %             end
        %         end
        %
        %         function r=rad(app,s)
        %             switch s
        %                 case  'H', r = 0.4;
        %                 case  'C', r = 0.5;
        %                 case  'O', r = 0.5;
        %                 case  'N', r = 0.5;
        %                 case  'I', r = 0.6;
        %                 otherwise, r = 0.5;
        %             end
        %         end
        
        
        function GetExpSolvent(app)
            
            if strcmp(app.NoiseFreeSwitch.Value,'Noise free')
                qMIN=app.qMINEditField.Value;
                dq=app.dqEditField.Value;
                qMAX=app.qMAXEditField.Value;
                app.config.q=[qMIN:dq:qMAX]';
                app.config.std=app.config.q*0;
                app.config.scaling_factor_ref=1;
                
                
                app.config.pulse_repetition_ref=app.PulseRepetition.Value; %#ok<ADPROP>
                
                
                app.config.exposure_time_ref=app.ExposureTime.Value; %#ok<ADPROP>
                app.config.photons_per_pulse_ref=app.PhotonsPerPulse.Value; %#ok<ADPROP>
                %app.config.scaling_factor_ref=scaling_factor;
                app.config.lambda_ref=0.01;
                app.config.distance_ref=app.SampleDistance.Value;
                
                app.config.distance=app.config.distance_ref;
                app.config.scaling_factor=app.config.scaling_factor_ref;
                app.config.lambda=app.config.lambda_ref;
                
            elseif strcmp(app.NoiseFreeSwitch.Value,'Experimental noise')
                try
                    load(app.NoiseFileEditField.Value);
                catch
                    error('Unknown error found in solvent standard deviation files. Check the files.');
                end
                %             app.config.q=q_exp;
                %             app.config.mean=mean_exp;
                %             app.config.std=std_exp;
                %             app.config.photonflux=1;
                %             app.config.scaling_factor=Scaling_factor;
                
                app.config.q=q_exp;
                app.config.std=std_exp;
                app.config.scaling_factor_ref=scaling_factor;
                app.config.pulse_repetition_ref=pulse_repetition; %#ok<ADPROP>
                app.config.exposure_time_ref=exposure_time; %#ok<ADPROP>
                app.config.photons_per_pulse_ref=photons_per_pulse; %#ok<ADPROP>
                %app.config.scaling_factor_ref=scaling_factor;
                app.config.lambda_ref=lambda;
                app.config.distance_ref=distance;
                
                app.config.distance=app.config.distance_ref;
                app.config.scaling_factor=app.config.scaling_factor_ref;
                app.config.lambda=app.config.lambda_ref;
            end


        end
        
        
        function CalCage(app)
            %% Add path
            switch app.CageMethodknob.Value
                case 'None'
                    app.config.cage=zeros(length(app.config.q),1);
                    app.results.cage=app.config.cage;
                case 'MD simulations'
                    cage_data=dlmread(app.CageFileEditField.Value);
                    q_MD=cage_data(:,1);
                    sq_MD=cage_data(:,2);
                    
                    cage_temp=interp1(q_MD,sq_MD,app.config.q,'spline');
                    
                    app.config.cage=cage_temp*app.config.exct/app.config.R;
                    app.results.cage=app.config.cage;
                    
                case 'Hard-spheres'
                    %                     path_current=app.WorkingDirectory.Value ;
                    %                     temp=sprintf('%s/codes',path_current);
                    %                     addpath(temp)
                    numofsolv=1024;
                    q=app.config.q;
                    NumQ=length(q);
                    mw=app.config.mw * 1e3; % g/mol
                    na=app.constant.Na;
                    dens=app.config.dens; % Kg/m3
                    V=numofsolv*mw/na/(dens*1000)*10^30;
                    
                    cage_elements_gro=zeros(NumQ,app.config.numgro);
                    cage_elements_pro=zeros(NumQ,app.config.numpro);
                    
                    for ii=1:app.config.numgro+app.config.numpro
                        % Geo specific
                        clearvars Geo
                        if ii < app.config.numgro+1
                            Geo=app.config.coord_gro{ii};
                        else
                            Geo=app.config.coord_pro{ii-app.config.numgro};
                        end
                        
                        
                        Geo.num=sym2numOld(app,Geo);
                        Geo.R=geo2R(app,Geo.data);
                        Geo.VDR=geo2VDR(app,Geo.num);
                        
                        % Make element
                        %num_atom_type_solv=size(app.config.solvent_element,1);
                        atom_type_solv=app.config.solvent_element(:,1);
                        num_atom_type_solv=length(atom_type_solv);
                        atom_type_solu=unique(Geo.num);
                        num_atom_type_solu=length(atom_type_solu);
                        atom=cell(num_atom_type_solv,2);
                        solvent=cell(1,num_atom_type_solv);
                        for index_atom_type=1:num_atom_type_solv
                            solvent(index_atom_type)=app.constant.elements(atom_type_solv(index_atom_type));
                        end
                        for index_atom=1:num_atom_type_solv
                            atom(index_atom,:)={app.constant.elements{app.config.solvent_element(index_atom,1)},numofsolv*app.config.solvent_element(index_atom,2)};
                        end
                        for index_atom=num_atom_type_solv+1:num_atom_type_solv+num_atom_type_solu
                            temp_element=sprintf('%s_1',app.constant.elements{atom_type_solu(index_atom-num_atom_type_solv)});
                            atom(index_atom,:)={temp_element,sum(Geo.num==atom_type_solu(index_atom-num_atom_type_solv))};
                        end
                        
                        
                        % Pair Cell Maker
                        numoftype=length(atom);
                        numofpair=sum([1:1:numoftype]); %#ok<NBRAK>
                        clear pair_cell
                        pair_cell=cell(1,numofpair);
                        count=0;
                        for type_index=1:numoftype
                            for type_index_2=type_index:numoftype
                                count=count+1;
                                temp=sprintf('%s-%s',atom{type_index,1},atom{type_index_2,1});
                                pair_cell{count}=temp;
                            end
                        end


                        % Pair Maker
                        [pair,Z,N,PDF_cutoff]=AnalyzePair(app,pair_cell,atom);
                        r=[0.01:0.01:20]; %#ok<NBRAK>
                        numofr=length(r);
                        gr=zeros(numofr,numofpair);
                        for pair_counter=1:numofpair
                            gr(:,pair_counter)=gr_convert(app,r,PDF_cutoff(pair_counter),Geo.R);
                        end
                        dr=diff(r);
                        %disp(gr)
                        
                        % Formfactor
                        f=nan(2,numofpair,NumQ);
                        for counter_pair=1:numofpair
                            for counter=1:2
                                for counter_q=1:NumQ
                                    f(counter,counter_pair,counter_q)=GetFormFactor(app,Z(counter,counter_pair),q(counter_q));
                                end
                            end
                        end
                        signal=zeros(NumQ,numofpair);
                        for iii=1:NumQ
                            for jj=1:numofpair
                                fact=2;
                                if 1==strcmp(pair{1,jj},pair{2,jj})
                                    signal(iii,jj)=signal(iii,jj)+f(1,jj,iii)^2*N(1,jj);
                                    fact=1;
                                else
                                end
                                if gr(end,jj)>0.25
                                    substration=1;
                                else
                                    substration=0;
                                end
                                for kk=1:numofr-1
                                    signal(iii,jj)=signal(iii,jj)+N(1,jj)*N(2,jj)/V*f(1,jj,iii)*f(2,jj,iii)*4*pi*r(kk)*sin(q(iii)*r(kk))/q(iii)*(gr(kk,jj)-substration)*fact*dr(kk);
                                end
                            end
                        end
                        %cage_results=[q,sum(signal,2),signal];
                        
                        %% Solvent checker
                        count=0;
                        count2=0;
                        count_array=[];
                        for counter_pair=1:numofpair
                            checker=0;
                            count=count+1;
                            for jj=1:2
                                for kk=1:length(solvent)
                                    if 1==strcmp(pair{jj,counter_pair},solvent{kk})
                                        checker=checker+1;
                                    end
                                end
                            end
                            if checker==1
                                count2=count2+1;
                                count_array(count2)=count; %#ok<AGROW>
                            end
                        end
                        clear pair_cell_cage
                        pair_cell_cage=cell(1,length(count_array));
                        signal_cage=nan(NumQ,length(count_array));
                        for pair_counter=1:length(count_array)
                            pair_cell_cage{pair_counter}=pair_cell{count_array(pair_counter)};
                            signal_cage(:,pair_counter)=signal(:,count_array(pair_counter));
                        end
                        cage_results_temp=sum(signal_cage,2);
                        if ii < app.config.numgro+1
                            cage_elements_gro(:,ii)=cage_results_temp*app.config.fraction_gro(ii);
                        else
                            cage_elements_pro(:,ii-app.config.numgro)=cage_results_temp*app.config.fraction_pro(ii-app.config.numgro);
                        end
                    end
                    app.config.cage_gro=sum(cage_elements_gro,2);
                    app.config.cage_pro=sum(cage_elements_pro,2);
                    app.config.cage=(app.config.cage_pro-app.config.cage_gro)*app.config.exct/app.config.R;
                    app.results.cage=app.config.cage;
            end
        end
        
        function R = geo2R(~,coord)
            COM=zeros(1,3);
            [NumAtom,~]=size(coord);
            for ii=1:3
                COM(ii)=sum(coord(:,ii)/NumAtom);
            end
            
            dis_COM=(coord-COM).^2;
            
            test2=zeros(1,NumAtom);
            for ii=1:NumAtom
                test2(ii)=sum(dis_COM(ii,:));
            end
            
            R=sqrt(sum(test2)/NumAtom);
        end
        
        function VDradius=geo2VDR(app,atom_numbers)
            VDradius=zeros(1,length(atom_numbers));
            for ii=1:length(atom_numbers)
                VDradius(ii)=app.constant.vanderwaals(atom_numbers(ii));
            end
        end
        
        function number=atom_count(~,text,atom)
            [NumAtom,~]=size(atom);
            for ii=1:NumAtom
                if 1==strcmp(atom{ii,1},text)
                    number=atom{ii,2};
                else
                end
            end
        end
        
        function results=GetFormFactor(app,atomic_number,q)
            s=q/(4*pi);
            %% Simple calculate for An,Bn,Cn term (D. Waasmaier and A. Kirfel)
            temp=0;
            for k=1:5
                temp=temp+app.constant.Xfactor(atomic_number,k)*exp(-app.constant.Xfactor(atomic_number,k+6)*s*s);
            end
            results=temp+app.constant.Xfactor(atomic_number,6);
        end
        
        function CorrectionMaximumQ(app)
            
            %             app.config.distance_target=app.SampleDistance.Value;
            %             distance_ratio=app.config.distance_target/app.config.distance;
            %
            %             q_max_ref=app.config.q(end);
            %
            %             theta_max=asin(app.config.lambda*q_max_ref/4/pi)*180/pi;
            %             theta_coorection=atan(tan(2*theta_max/180*pi)/distance_ratio)/2*180/pi;
            %
            %             q_max_coorection=sin(theta_coorection/180*pi)*4*pi/app.config.lambda;
            %
            %             q_index= app.config.q<=q_max_coorection;
            %
            %             app.config.q=app.config.q(q_index);
            %             app.config.std=app.config.std(q_index);
            app.config.distance_target=app.SampleDistance.Value;
            distance_ratio=app.config.distance_target/app.config.distance;
            
            q_max_ref=app.config.q(end);
            
            theta_max=asin(app.config.lambda*q_max_ref/4/pi)*180/pi;
            
            if theta_max > 45
                error('Mismatch between sacttering vector(q) and X-ray wavelength. Check the q and lambda.')
            end
            
            theta_coorection=atan(tan(2*theta_max/180*pi)/distance_ratio)/2*180/pi;
            
            q_max_coorection=sin(theta_coorection/180*pi)*4*pi/app.config.lambda;
            
            q_index= app.config.q<=q_max_coorection;
            
            app.config.q=app.config.q(q_index);
            app.config.std=app.config.std(q_index);
        end
        
        function num = sym2numOld(app,coord)
            [NumAtom,~]=size(coord.data);
            num=nan(NumAtom,1);
            for atom=1:NumAtom % change atom to element number
                for i=1:104
                    if strcmp(coord.textdata{atom},app.constant.elements{i})==1
                        num(atom)=i;
                    else
                    end
                end
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %files = matlab.apputil.getInstalledAppInfo;
            
            %             for ii=1:length(files)
            %                 if strcmp(files(ii).name,'Scube')
            %                     app_path=sprintf('%s/code',lower(files(ii).location));
            %                     cd(app_path)
            %                 end
            %             end
            
                        set(app.WorkingDirectory,'Value',pwd);
                        WorkingDirectoryValueChanged(app);
            %
            %             temp=sprintf('%s/Scube_config*.dat',app.WorkingDirectory.Value);
            %
            %             list=dir(temp);
            %             if ~isempty(list)
            %
            %                 temp=cell(length(list),1);
            %                 for ii=1:length(list)
            %                     temp{ii}=list(ii).name;
            %                 end
            %                 sorted=sort(temp);
            %
            %                 for ii=1:length(list)
            %                     if strcmp(temp{ii},sorted{end})
            %                         index=ii;
            %                     end
            %                 end
            %                 name=list(index).name;
            %                 path=list(index).folder;
            %                 ReadConfig(app,path,name,1);
            %                 path_name=sprintf('%s/%s',path,name);
            %                 set(app.ConfigurationFileEditField,'Value',path_name);
            %             end
            
            fprintf('---------------------------------------------------------\n');
            fprintf('                        S-cube                           \n');
            fprintf('    Time-resolved X-ray solution scattering simulator    \n');
            fprintf('---------------------------------------------------------\n');
            
            
        end

        % Callback function
        function Slider_concValueChanged(app, event)
            value = app.Slider_conc.Value;
            app.Conc.Value=value;
        end

        % Callback function
        function Slider_concValueChanging(app, event)
            changingValue = event.Value; %#ok<NASGU>
        end

        % Value changed function: Conc
        function ConcValueChanged(app, event)
            value = app.Conc.Value; %#ok<NASGU>
            %app.Slider_conc.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_conc.Value=value;
        end

        % Callback function
        function Slider_exctValueChanged(app, event)
            value = app.Slider_exct.Value;
            app.Exct.Value=value;
        end

        % Callback function
        function Slider_exctValueChanging(app, event)
            changingValue = event.Value; %#ok<NASGU>
        end

        % Value changed function: Exct
        function ExctValueChanged(app, event)
            %value = app.Exct.Value;
            %app.Slider_exct.Value=value;
            value = app.Exct.Value; %#ok<NASGU>
            %app.Slider_exct.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_exct.Value=value;
        end

        % Value changed function: FileGro
        function FileGroValueChanged(app, event)
            %value = app.FileGro.Value;
            %while isempty(app.FileGro.Value{end})||strcmp(' ',app.FileGro.Value{end}(1))
            %    app.FileGro.Value=app.FileGro.Value(1:end-1);
            %end
        end

        % Value changed function: FilePro
        function FileProValueChanged(app, event)
            %value = app.FilePro.Value;
            %while isempty(app.FilePro.Value{end})||strcmp(' ',app.FilePro.Value{end}(1))
            %    app.FilePro.Value=app.FilePro.Value(1:end-1);
            %end
        end

        % Callback function
        function Slider_hourValueChanged(app, event)
            value = app.Slider_hour.Value;
            app.Hour.Value=value;
        end

        % Callback function
        function Slider_fluxValueChanged(app, event)
            value = app.Slider_flux.Value;
            app.PhotonsPerPulse.Value=value;
        end

        % Callback function
        function Slider_repeValueChanged(app, event)
            value = app.Slider_repe.Value;
            app.PulseRepetition.Value=value;
        end

        % Callback function
        function Slider_paraValueChanged(app, event)
            value = app.Slider_para.Value;
            app.DutyCycle.Value=value;
        end

        % Callback function
        function Slider_acculValueChanged(app, event)
            value = app.Slider_accul.Value;
            app.ExposureTime.Value=value;
        end

        % Value changed function: Hour
        function HourValueChanged(app, event)
            value = app.Hour.Value; %#ok<NASGU>
            %app.Slider_hour.Limits=[0 10^floor(log10(value)+1)];
            %app.Slider_hour.Value=value;
        end

        % Value changed function: PhotonsPerPulse
        function PhotonsPerPulseValueChanged(app, event)
            value = app.PhotonsPerPulse.Value; %#ok<NASGU>
            %app.Slider_flux.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_flux.Value=value;
        end

        % Value changed function: PulseRepetition
        function PulseRepetitionValueChanged(app, event)
            value = app.PulseRepetition.Value; %#ok<NASGU>
            %app.Slider_repe.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_repe.Value=value;
        end

        % Value changed function: DutyCycle
        function DutyCycleValueChanged(app, event)
            value = app.DutyCycle.Value; %#ok<NASGU>
            %if 10^floor(log10(value)+1)<=1
            %    app.Slider_para.Limits=[0 10^floor(log10(value)+1)];
            %end
            %app.Slider_para.Value=value;
        end

        % Value changed function: ExposureTime
        function ExposureTimeValueChanged(app, event)
            value = app.ExposureTime.Value; %#ok<NASGU>
            %app.Slider_accul.Limits=[1 10^floor(log10(value)+1)];
            %app.Slider_accul.Value=value;
        end

        % Button pushed function: SaveConfigurationButton
        function SaveConfigurationButtonButtonPushed(app, event)
            time=datestr(now,'yymmdd_HHMMSS');
            path=uigetdir(pwd,'Select a directory to save the configuration files');
            app.config.vervose=1;
            WriteConfig(app,time,path);
            path_file=sprintf('%s/Scube_config_%s.dat',path,time);
            set(app.ConfigurationFileEditField,'Value',path_file);
            %path=cd;
            
            %list=dir('config/*.dat');
            
            %             time_file=datestr(now,'yymmdd_HHMMSS');
            %             NameFile=sprintf('/config/Scube_config_%s.dat',time_file);
            %             ID_file=fopen(NameFile,'w');
            %
            %             fprintf(ID_file,'num_ground_coord\n');
            %             temp=sprintf('%d\n\n',length(app.FileGro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'num_product_coord\n');
            %             temp=sprintf('%d\n\n',length(app.FilePro.Value));
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'path_ground_coord\n');
            %
            %             for ii=1:length(app.FileGro.Value)
            %                 temp=sprintf('%s\n',app.FileGro.Value{ii});
            %                 fprintf(ID_file,temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'path_product_coord\n');
            %
            %             for ii=1:length(app.FilePro.Value)
            %                 temp=sprintf('%s\n',app.FilePro.Value{ii});
            %                 fprintf(ID_file,temp);
            %             end
            %             fprintf(ID_file,'\n');
            %
            %             fprintf(ID_file,'Solvent\n');
            %             temp=sprintf('%s\n\n',app.Solvent.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Concentration\n');
            %             temp=sprintf('%.4g\n\n',app.Conc.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Quantum_yield\n');
            %             temp=sprintf('%.4g\n\n',app.Exct.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Wavelength_of_pump_laser\n');
            %             temp=sprintf('%.4g\n\n',app.Wave.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Accumulation_time(hours)\n');
            %             temp=sprintf('%.4g\n\n',app.Hour.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Photon_flux(Number_of_photons/shot)\n');
            %             temp=sprintf('%d\n\n',app.PhotonsPerPulse.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Repetition_Rate\n');
            %             temp=sprintf('%d\n\n',app.PulseRepetition.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Vitual_accumulation_number\n');
            %             temp=sprintf('%d\n\n',app.ExposureTime.Value);
            %             fprintf(ID_file,temp);
            %
            %             fprintf(ID_file,'Practice_parameter\n');
            %             temp=sprintf('%.4g\n\n',app.DutyCycle.Value);
            %             fprintf(ID_file,temp);
            %
            %
            %
            %             fclose(ID_file);
        end

        % Button pushed function: LoadConfigurationButton
        function LoadConfigurationButtonButtonPushed(app, event)
            
%             [file, path] = uigetfile({'*.dat;','Config file (*.dat)';'*.*',  'All Files (*.*)'},'Select the config file');
[file, path] = uigetfile({'*.mat;','Config file (*.mat)';'*.dat;','Config file (*.dat)';'*.*',  'All Files (*.*)'},'Select the config file');
            if isequal(file,0)
            else
                ReadConfig(app,path,file);
            end
            PathFile=sprintf('%s/%s',path,file);
            set(app.ConfigurationFileEditField,'Value',PathFile);
            
        end

        % Button pushed function: SimulateScubeButton
        function SimulateScubeButtonButtonPushed(app, event)
            
            WorkingDirectoryValueChanged(app);
            
            time=datestr(now,'yymmdd_HHMMSS');
            
            fprintf('               Reading input data initiate.               \n');
            SetConfig(app);
            GetSolventInfo(app);
            GetExpSolvent(app);
            CorrectionMaximumQ(app);
            ReadConstant(app);
            ReadCoord(app);
            ReadFraction(app);
            fprintf('               Reading input data terminated.             \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('           Calculating debye equation initiate.           \n');
            app.config.vervose=1;
            CalSolute(app);
            fprintf('          Calculating debye equation terminated.          \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('      Calculating solvent and cage signal initiate.       \n');
            CalSolvent(app);
            CalCage(app);
            fprintf('     Calculating solvent and cage signal terminated.      \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('                    Simulation initiate.                  \n');
            CalNoise(app);
            fprintf('----------------------------------------------------------\n');
            MergeSimulationResults(app);
            fprintf('----------------------------------------------------------\n');
            fprintf('                   Simulation terminated.                 \n');
            fprintf('----------------------------------------------------------\n');
            
            fprintf('                      Plotting figure                     \n');
            DrawScubeResults(app);
            if app.MakeResultsCheckBox.Value==1
                WriteOutput(app,time,app.WorkingDirectory.Value);
            end
            fprintf('                   Writing output files.                  \n');
            fprintf('                S-cube simulation completed.              \n');
            fprintf('----------------------------------------------------------\n');
        end

        % Value changed function: MakeResultsCheckBox
        function MakeResultsCheckBoxValueChanged(app, event)
%             value = app.MakeResultsCheckBox.Value;
%             if value == 1
%                 app.WorkingDirectory.Enable=1;
%                 app.GetDirectoryButton.Enable=1;
%             elseif value == 0
%                 app.WorkingDirectory.Enable=0;
%                 app.GetDirectoryButton.Enable=0;
%             end
        end

        % Button pushed function: GetDirectoryButton
        function GetDirectoryButtonButtonPushed(app, event)
            dir_temp=uigetdir;
            if isequal(dir_temp,0)
            else
                app.WorkingDirectory.Value=dir_temp;
                WorkingDirectoryValueChanged(app);
            end
        end

        % Button pushed function: Draw
        function DrawButtonPushed(app, event)
            %             SetConfig(app);
            %             GetSolventInfo(app);
            %             ReadConstant(app);
            %             ReadCoord(app);
            %
            %             for ii=1:length(app.config.coord_gro)
            %                 figure
            %                 molecule3D(app.config.coord_gro{ii}.data,app.config.coord_gro{ii}.textdata);
            %             end
            %
            %             for ii=1:length(app.config.coord_pro)
            %                 figure
            %                 molecule3D(app.config.coord_pro{ii}.data,app.config.coord_pro{ii}.textdata);
            %             end
        end

        % Button pushed function: GetNoiseFileButton
        function GetNoiseFileButtonPushed(app, event)
            [file, path] = uigetfile({'*.mat;','Solvent experimental data (*.mat)';'*.*',  'All Files (*.*)'},'Select the solvent experimental data');
            if isequal(file,0)
            else
                app.NoiseFileEditField.Value=sprintf('%s/%s',path,file);
            end
        end

        % Button pushed function: GetReactantButton
        function GetReactantButtonButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.dat','XYZ format file(*.txt,*.dat)'},...
                'Select One or More coordination files', ...
                'MultiSelect', 'on');
            
            if isequal(file,0)
            elseif iscell(file)
                for ii=1:length(file)
                    app.FileGro.Value{ii}=sprintf('%s%s',path,file{ii});
                end
            else
                app.FileGro.Value{1}=sprintf('%s%s',path,file);
            end
        end

        % Button pushed function: GetProductButton
        function GetProductButtonButtonPushed(app, event)
            [file,path] = uigetfile({'*.txt;*.dat','XYZ format file(*.txt,*.dat)'},...
                'Select One or More coordination files', ...
                'MultiSelect', 'on');
            
            if isequal(file,0)
            elseif iscell(file)
                for ii=1:length(file)
                    app.FilePro.Value{ii}=sprintf('%s%s',path,file{ii});
                end
            else
                app.FilePro.Value{1}=sprintf('%s%s',path,file);
            end
        end

        % Value changed function: dtSwitch
        function dtSwitchValueChanged(app, event)
            value = app.dtSwitch.Value; %#ok<NASGU>
            if strcmp(app.dtSwitch.Value,'Off') && strcmp(app.drhoSwitch.Value,'Off')
                app.Wave.Enable=0;
            else
                app.Wave.Enable=1;
            end
        end

        % Value changed function: drhoSwitch
        function drhoSwitchValueChanged(app, event)
            value = app.drhoSwitch.Value; %#ok<NASGU>
            if strcmp(app.dtSwitch.Value,'Off') && strcmp(app.drhoSwitch.Value,'Off')
                app.Wave.Enable=0;
            else
                app.Wave.Enable=1;
            end
        end

        % Value changed function: CageMethodknob
        function CageMethodknobValueChanged(app, event)
            value = app.CageMethodknob.Value;
            if strcmp(value,'MD simulations')
                set(app.CageFileEditField,'Visible',1);
                set(app.GetCageButton,'Visible',1);
                
                set(app.CagefilesnameLabel,'Visible',1);
                %set(app.CagefilesnameLabel,'Text','Cage files name');
                
                set(app.SamplingDrEditField,'Visible',0);
                set(app.SamplingdrEditFieldLabel,'Text',' ');
                
%                 set(app.SamplingDrEditField.,'Text',' ');
            elseif strcmp(value,'None')
                set(app.CageFileEditField,'Visible',0);
                set(app.GetCageButton,'Visible',0);
%                 set(app.CagefilesnameLabel,'Text',' ');
                set(app.CagefilesnameLabel,'Visible',0);
                
                set(app.SamplingDrEditField,'Visible',0);
                set(app.SamplingdrEditFieldLabel,'Text',' ');
            elseif strcmp(value,'Hard-spheres')
                set(app.CageFileEditField,'Visible',0);
                set(app.GetCageButton,'Visible',0);
                set(app.CagefilesnameLabel,'Visible',0);
                
                set(app.SamplingDrEditField,'Visible',1);
                set(app.SamplingdrEditFieldLabel,'Text','Sampling dr');
            else
                %set(app.CageFileEditField,'Visible',0);
                %set(app.GetCageButton,'Visible',0);
            end
        end

        % Button pushed function: GetCageButton
        function GetCageButtonButtonPushed(app, event)
            [file, path] = uigetfile({'*.dat;','Cage file (*.dat)';'*.*',  'All Files (*.*)'},'Select the cage file');
            path_file=sprintf('%s/%s',path,file);
            set(app.CageFileEditField,'Value',path_file);
        end

        % Button pushed function: SimulateScubeBatchButton
        function SimulateScubeBatchButtonButtonPushed(app, event)
            dir_temp=uigetdir;
            set(app.WorkingDirectory.Value,dir_temp);
            WorkingDirectoryValueChanged(app);
            file_list_wildcard=sprintf('%s/Scube_config_*.dat',dir_temp);
            file_list=dir(file_list_wildcard);
            for index_file=1:length(file_list)
                ReadConfig(app,file_list(index_file).folder,file_list(index_file).name)
                
                time=datestr(now,'yymmdd_HHMMSS');
                SetConfig(app);
                GetSolventInfo(app);
                GetExpSolvent(app);
                ReadConstant(app);
                ReadCoord(app);
                ReadFraction(app);
                app.config.vervose=1;
                CalSolute(app);
                CalSolvent(app);
                CalCage(app);
                
                CalNoise(app);
                MergeSimulationResults(app);
                DrawScubeResults(app);
                if app.MakeResultsCheckBox.Value==1
                    WriteOutput(app,time,app.WorkingDirectory.Value);
                end
            end
        end

        % Value changed function: SampleDistance
        function SampleDistanceValueChanged(app, event)
            value = app.SampleDistance.Value; %#ok<NASGU>
        end

        % Value changed function: WorkingDirectory
        function WorkingDirectoryValueChanged(app, event)
            value = app.WorkingDirectory.Value;
            try
                cd(value);
            catch
                error('Working Directory name Error !!!');
            end
            fprintf('       Wokring Directory : %s\n',pwd);
            fprintf('---------------------------------------------------------\n');
        end

        % Value changed function: AtomicPositionSwitch
        function AtomicPositionSwitchValueChanged(app, event)
            value = app.AtomicPositionSwitch.Value;
            if strcmp(value,'On')
                set(app.AtomicPositionLamp,'Color',[0.00,1.00,0.00]);
                set(app.SpiralSamplingNumberEditField,'Visible',1);
                set(app.SpiralsamplingnumberEditFieldLabel,'Visible',1);
                set(app.SpiralRadiusEditField,'Visible',1);
                set(app.SpiralradiusLabel,'Visible',1);
            elseif strcmp(value,'Off')
                set(app.AtomicPositionLamp,'Color',[1.00,0.00,0.00]);
                set(app.SpiralSamplingNumberEditField,'Visible',0);
                set(app.SpiralsamplingnumberEditFieldLabel,'Visible',0);
                set(app.SpiralRadiusEditField,'Visible',0);
                set(app.SpiralradiusLabel,'Visible',0);
            else
                error(0);
            end
        end

        % Value changed function: InternuclearDistanceSwitch
        function InternuclearDistanceSwitchValueChanged(app, event)
            value = app.InternuclearDistanceSwitch.Value;
            if strcmp(value,'On')
                set(app.InternuclearDistanceLamp,'Color',[0.00,1.00,0.00]);
                set(app.ChangingNuclearDistanceEditField,'Visible',1);
                set(app.ChangingnucleardistanceLabel,'Visible',1);
            elseif strcmp(value,'Off')
                set(app.InternuclearDistanceLamp,'Color',[1.00,0.00,0.00]);
                set(app.ChangingNuclearDistanceEditField,'Visible',0);
                set(app.ChangingnucleardistanceLabel,'Visible',0);
            else
                error(0);
            end
        end

        % Value changed function: NoiseFreeSwitch
        function NoiseFreeSwitchValueChanged(app, event)
            value = app.NoiseFreeSwitch.Value;
            if strcmp(value,'Experimental noise')
                set(app.NoiseFileEditField,'Visible',1);
                set(app.GetNoiseFileButton,'Visible',1);
                set(app.qMINEditFieldLabel,'Visible',0)
                set(app.dqEditFieldLabel,'Visible',0);
                set(app.qMAXEditFieldLabel,'Visible',0);
                set(app.qMINEditField,'Visible',0)
                set(app.dqEditField,'Visible',0);
                set(app.qMAXEditField,'Visible',0);
            elseif strcmp(value,'Noise free')
                set(app.NoiseFileEditField,'Visible',0);
                set(app.GetNoiseFileButton,'Visible',0);
                set(app.qMINEditFieldLabel,'Visible',1);
                set(app.dqEditFieldLabel,'Visible',1);
                set(app.qMAXEditFieldLabel,'Visible',1);
                set(app.dqEditField,'Visible',1);
                set(app.qMAXEditField,'Visible',1);
                set(app.qMINEditField,'Visible',1);
            else
                error(0);
            end
        end

        % Button pushed function: SimulateSensitivityButton
        function SimulateSensitivityButtonPushed(app, event)
            WorkingDirectoryValueChanged(app);
            app.config.time=datestr(now,'yymmdd_HHMMSS');
            
            %time=datestr(now,'yymmdd_HHMMSS');
            
            %             fprintf('               Reading input data initiate.               \n');
            SetConfig(app);
            GetSolventInfo(app);
            GetExpSolvent(app);
            CorrectionMaximumQ(app);
            ReadConstant(app);
            ReadCoord(app);
            ReadFraction(app);
            %             fprintf('               Reading input data terminated.             \n');
            %             fprintf('----------------------------------------------------------\n');
            
            if strcmp(get(app.AtomicPositionSwitch,'Value'),'On')
                app.config.vervose=1;
                CalSolute(app);
                CalSolvent(app);
                CalCage(app);
                fprintf('----------------------------------------------------------\n');
                fprintf('     Calculating Atomic Position Sensitivity initiate.    \n');
                fprintf('----------------------------------------------------------\n');
                SensitivityAtomicPosition(app);
                fprintf('----------------------------------------------------------\n');
                fprintf('    Calculating Atomic Position Sensitivity terminated.   \n');
                fprintf('                   Writing output files.                  \n');
                fprintf('----------------------------------------------------------\n');
            elseif strcmp(get(app.AtomicPositionSwitch,'Value'),'Off')
                fprintf('            Skip Atomic Position Sensitivity              \n');
                fprintf('----------------------------------------------------------\n');
            else
                error(0);
            end
            
            if strcmp(get(app.InternuclearDistanceSwitch,'Value'),'On')
                fprintf('  Calculating Internuclear Distance Sensitivity initiate. \n');
                fprintf('----------------------------------------------------------\n');
                app.config.vervose=1;
                CalSolute(app);
                fprintf('----------------------------------------------------------\n');
                SensitivityInternuclearDistance(app);
                fprintf('----------------------------------------------------------\n');
                fprintf('  Calculating Internuclear Distance Sensitivity terminated.\n');
                fprintf('                   Writing output files.                  \n');
                fprintf('----------------------------------------------------------\n');
            elseif strcmp(get(app.InternuclearDistanceSwitch,'Value'),'Off')
                fprintf('         Skip Internuclear Distance Sensitivity           \n');
                fprintf('----------------------------------------------------------\n');
            else
                error(0);
            end
            
            %             fprintf('           Calculating debye equation initiate.           \n');
            %             CalSolute(app);
            %             fprintf('          Calculating debye equation terminated.          \n');
            %             fprintf('----------------------------------------------------------\n');
            %
            %             fprintf('      Calculating solvent and cage signal initiate.       \n');
            %             CalSolvent(app);
            %             CalCage(app);
            %             fprintf('     Calculating solvent and cage signal terminated.      \n');
            %             fprintf('----------------------------------------------------------\n');
            %
            %             fprintf('                    Simulation initiate.                  \n');
            %             CalNoise(app);
            %             MergeSimulationResults(app);
            %             fprintf('                   Simulation terminated.                 \n');
            %             fprintf('----------------------------------------------------------\n');
            %
            %             fprintf('                      Plotting figure                     \n');
            %             DrawScubeResults(app);
            %             if app.MakeResultsCheckBox.Value==1
            %                 WriteOutput(app,time,app.WorkingDirectory.Value);
            %             end
            %             fprintf('                   Writing output files.                  \n');
            fprintf('                 Sensitivity Plot completed.              \n');
            fprintf('----------------------------------------------------------\n');
        end

        % Value changed function: Solvent
        function SolventValueChanged(app, event)
            value = app.Solvent.Value;
            
        end

        % Value changed function: ConfigurationFileEditField
        function ConfigurationFileEditFieldValueChanged(app, event)
            value = app.ConfigurationFileEditField.Value;
            
        end

        % Value changed function: VibCool
        function VibCoolValueChanged(app, event)
            value = app.VibCool.Value;
            
        end

        % Value changed function: SpiralSamplingNumberEditField
        function SpiralSamplingNumberEditFieldValueChanged(app, event)
            value = app.SpiralSamplingNumberEditField.Value;
            
        end

        % Value changed function: SpiralRadiusEditField
        function SpiralRadiusEditFieldValueChanged(app, event)
            value = app.SpiralRadiusEditField.Value;
            
        end

        % Value changed function: ChangingNuclearDistanceEditField
        function ChangingNuclearDistanceEditFieldValueChanged(app, event)
            value = app.ChangingNuclearDistanceEditField.Value;
            
        end

        % Value changed function: Wave
        function WaveValueChanged(app, event)
            value = app.Wave.Value;
            
        end

        % Value changed function: SamplingDrEditField
        function SamplingDrEditFieldValueChanged(app, event)
            value = app.SamplingDrEditField.Value;
            
        end

        % Value changed function: CageFileEditField
        function CageFileEditFieldValueChanged(app, event)
            value = app.CageFileEditField.Value;
            
        end

        % Value changed function: dqEditField
        function dqEditFieldValueChanged(app, event)
            value = app.dqEditField.Value;
            
        end

        % Value changed function: qMAXEditField
        function qMAXEditFieldValueChanged(app, event)
            value = app.qMAXEditField.Value;
            
        end

        % Value changed function: NoiseFileEditField
        function NoiseFileEditFieldValueChanged(app, event)
            value = app.NoiseFileEditField.Value;
            
        end

        % Value changed function: FractionGro
        function FractionGroValueChanged(app, event)
            value = app.FractionGro.Value;
            
        end

        % Value changed function: FractionPro
        function FractionProValueChanged(app, event)
            value = app.FractionPro.Value;
            
        end

        % Value changed function: qMINEditField
        function qMINEditFieldValueChanged(app, event)
            value = app.qMINEditField.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.main = uifigure('Visible', 'off');
            app.main.Name = 'Scube';
            app.SolventpartPanel = uipanel(app.main);
            app.SolventpartPanel.Title = 'The Solvent Selection';
            app.SolventDropDownLabel = uilabel(app.SolventpartPanel);
            app.SolventDropDownLabel.Text = 'Solvent';
            app.Solvent = uidropdown(app.SolventpartPanel);
            app.Solvent.Items = {'Acetonitrile(CH3CN)', 'Water(H2O)', 'Methanol(CH3OH)', 'Cyclohexane(C6H12)', 'Custom'};
            app.Solvent.ValueChangedFcn = createCallbackFcn(app, @SolventValueChanged, true);
            app.Solvent.Value = 'Cyclohexane(C6H12)';
            app.SolutepartPanel = uipanel(app.main);
            app.SolutepartPanel.Title = 'The solute-only term';
            app.ReactantMoleculargeometryfilesLabel = uilabel(app.SolutepartPanel);
            app.ReactantMoleculargeometryfilesLabel.Text = {'Molecular '; 'geometry files'};
            app.FileGro = uitextarea(app.SolutepartPanel);
            app.FileGro.ValueChangedFcn = createCallbackFcn(app, @FileGroValueChanged, true);
            app.FileGro.Value = {'structure_example/CHI3_Scube.txt'};
            app.ConcentrationofsolutemMLabel = uilabel(app.SolutepartPanel);
            app.ConcentrationofsolutemMLabel.Text = 'Concentration of solute(mM)';
            app.Conc = uieditfield(app.SolutepartPanel, 'numeric');
            app.Conc.Limits = [0 Inf];
            app.Conc.ValueChangedFcn = createCallbackFcn(app, @ConcValueChanged, true);
            app.Conc.Value = 300;
            app.Excitationratiostructurallychangedsolutetotalsolute1Label = uilabel(app.SolutepartPanel);
            app.Excitationratiostructurallychangedsolutetotalsolute1Label.Text = {'Excitation ratio '; '(structurally changed '; 'solute/total solute) (<1)'};
            app.Exct = uieditfield(app.SolutepartPanel, 'numeric');
            app.Exct.Limits = [0 1];
            app.Exct.ValueChangedFcn = createCallbackFcn(app, @ExctValueChanged, true);
            app.Exct.Value = 0.1;
            app.ProductMoleculargeometryfilesLabel = uilabel(app.SolutepartPanel);
            app.ProductMoleculargeometryfilesLabel.Text = {'Molecular '; 'geometry files'};
            app.FilePro = uitextarea(app.SolutepartPanel);
            app.FilePro.ValueChangedFcn = createCallbackFcn(app, @FileProValueChanged, true);
            app.FilePro.Value = {'structure_example/CHI2I_Scube.txt'};
            app.Draw = uibutton(app.SolutepartPanel, 'push');
            app.Draw.ButtonPushedFcn = createCallbackFcn(app, @DrawButtonPushed, true);
            app.Draw.Enable = 'off';
            app.Draw.Visible = 'off';
            app.Draw.Text = 'Draw the molecular geometry files';
            app.GetReactantButton = uibutton(app.SolutepartPanel, 'push');
            app.GetReactantButton.ButtonPushedFcn = createCallbackFcn(app, @GetReactantButtonButtonPushed, true);
            app.GetReactantButton.Text = 'Get coordination';
            app.GetProductButton = uibutton(app.SolutepartPanel, 'push');
            app.GetProductButton.ButtonPushedFcn = createCallbackFcn(app, @GetProductButtonButtonPushed, true);
            app.GetProductButton.Text = {'Get coordination'; ''};
            app.FractionLabel = uilabel(app.SolutepartPanel);
            app.FractionLabel.Text = 'Fraction';
            app.FractionGro = uieditfield(app.SolutepartPanel, 'text');
            app.FractionGro.ValueChangedFcn = createCallbackFcn(app, @FractionGroValueChanged, true);
            app.FractionGro.Value = '1';
            app.FractionLabel_2 = uilabel(app.SolutepartPanel);
            app.FractionLabel_2.Text = 'Fraction';
            app.FractionPro = uieditfield(app.SolutepartPanel, 'text');
            app.FractionPro.ValueChangedFcn = createCallbackFcn(app, @FractionProValueChanged, true);
            app.FractionPro.Value = '1';
            app.ReactantGroundstateLabel = uilabel(app.SolutepartPanel);
            app.ReactantGroundstateLabel.Text = 'Reactant / Ground state';
            app.ProductIntermediateLabel = uilabel(app.SolutepartPanel);
            app.ProductIntermediateLabel.Text = 'Product / Intermediate';
            app.Thermalexcitationratiostructuralchangedsolutetotalsolute1Label = uilabel(app.SolutepartPanel);
            app.Thermalexcitationratiostructuralchangedsolutetotalsolute1Label.Text = {'Thermal excitation ratio '; '(Only thermally excited '; 'solute/total solute) (<1)'};
            app.VibCool = uieditfield(app.SolutepartPanel, 'numeric');
            app.VibCool.Limits = [0 1];
            app.VibCool.ValueChangedFcn = createCallbackFcn(app, @VibCoolValueChanged, true);
            app.VibCool.Value = 0.1;
            app.SolventselectPanel = uipanel(app.main);
            app.SolventselectPanel.Title = 'The solvent-only term';
            app.WavelengthofpumplasernmEditFieldLabel = uilabel(app.SolventselectPanel);
            app.WavelengthofpumplasernmEditFieldLabel.Text = 'Wavelength of pump laser(nm)';
            app.Wave = uieditfield(app.SolventselectPanel, 'numeric');
            app.Wave.Limits = [0 Inf];
            app.Wave.ValueChangedFcn = createCallbackFcn(app, @WaveValueChanged, true);
            app.Wave.Value = 267;
            app.dSdTLabel = uilabel(app.SolventselectPanel);
            app.dSdTLabel.Text = 'dS/dT';
            app.dtSwitch = uiswitch(app.SolventselectPanel, 'toggle');
            app.dtSwitch.ValueChangedFcn = createCallbackFcn(app, @dtSwitchValueChanged, true);
            app.dtSwitch.Value = 'On';
            app.dSdrhoLabel = uilabel(app.SolventselectPanel);
            app.dSdrhoLabel.Text = 'dS/drho';
            app.drhoSwitch = uiswitch(app.SolventselectPanel, 'toggle');
            app.drhoSwitch.ValueChangedFcn = createCallbackFcn(app, @drhoSwitchValueChanged, true);
            app.NoiseparPenel = uipanel(app.main);
            app.NoiseparPenel.Title = 'The noise term - Parameters for Simulation';
            app.CollectiontimeshoursLabel = uilabel(app.NoiseparPenel);
            app.CollectiontimeshoursLabel.Text = 'Collection times (hours)';
            app.Hour = uieditfield(app.NoiseparPenel, 'numeric');
            app.Hour.Limits = [0 Inf];
            app.Hour.ValueChangedFcn = createCallbackFcn(app, @HourValueChanged, true);
            app.Hour.Value = 1;
            app.PhotonspulseLabel = uilabel(app.NoiseparPenel);
            app.PhotonspulseLabel.Text = 'Photons/pulse';
            app.PhotonsPerPulse = uieditfield(app.NoiseparPenel, 'numeric');
            app.PhotonsPerPulse.Limits = [0 Inf];
            app.PhotonsPerPulse.RoundFractionalValues = 'on';
            app.PhotonsPerPulse.ValueChangedFcn = createCallbackFcn(app, @PhotonsPerPulseValueChanged, true);
            app.PhotonsPerPulse.Value = 1044932079;
            app.PulserepetitionrateHzLabel = uilabel(app.NoiseparPenel);
            app.PulserepetitionrateHzLabel.Text = {'Pulse repetition'; ' rate (Hz)'};
            app.PulseRepetition = uieditfield(app.NoiseparPenel, 'numeric');
            app.PulseRepetition.Limits = [0 Inf];
            app.PulseRepetition.ValueChangedFcn = createCallbackFcn(app, @PulseRepetitionValueChanged, true);
            app.PulseRepetition.Value = 957;
            app.DutycycleLabel = uilabel(app.NoiseparPenel);
            app.DutycycleLabel.Text = 'Duty cycle';
            app.DutyCycle = uieditfield(app.NoiseparPenel, 'numeric');
            app.DutyCycle.Limits = [0 1];
            app.DutyCycle.ValueChangedFcn = createCallbackFcn(app, @DutyCycleValueChanged, true);
            app.DutyCycle.Value = 1;
            app.ExposuretimessLabel = uilabel(app.NoiseparPenel);
            app.ExposuretimessLabel.Text = 'Exposure times (s)';
            app.ExposureTime = uieditfield(app.NoiseparPenel, 'numeric');
            app.ExposureTime.Limits = [0 Inf];
            app.ExposureTime.ValueChangedFcn = createCallbackFcn(app, @ExposureTimeValueChanged, true);
            app.ExposureTime.Value = 2;
            app.SampletodetectordistancemmLabel = uilabel(app.NoiseparPenel);
            app.SampletodetectordistancemmLabel.Text = {'Sample-to-detector'; 'distance(mm)'};
            app.SampleDistance = uieditfield(app.NoiseparPenel, 'numeric');
            app.SampleDistance.Limits = [0 Inf];
            app.SampleDistance.ValueChangedFcn = createCallbackFcn(app, @SampleDistanceValueChanged, true);
            app.SampleDistance.Value = 29.523;
            app.save = uipanel(app.main);
            app.save.Title = 'Working Directory';
            app.WorkingDirectory = uieditfield(app.save, 'text');
            app.WorkingDirectory.ValueChangedFcn = createCallbackFcn(app, @WorkingDirectoryValueChanged, true);
            app.WorkingDirectory.Value = ' ';
            app.GetDirectoryButton = uibutton(app.save, 'push');
            app.GetDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetDirectoryButtonButtonPushed, true);
            app.GetDirectoryButton.Text = 'Get directory';
            app.MakeResultsCheckBox = uicheckbox(app.save);
            app.MakeResultsCheckBox.ValueChangedFcn = createCallbackFcn(app, @MakeResultsCheckBoxValueChanged, true);
            app.MakeResultsCheckBox.Text = 'Make results data';
            app.MakeResultsCheckBox.Value = true;
            app.NoisepartPanel = uipanel(app.main);
            app.NoisepartPanel.Title = 'The noise term - Standard deviation of solvet scattering intensity files Selection';
            app.NoiseFileEditField = uieditfield(app.NoisepartPanel, 'text');
            app.NoiseFileEditField.ValueChangedFcn = createCallbackFcn(app, @NoiseFileEditFieldValueChanged, true);
            app.NoiseFileEditField.Value = 'sigma_solvent/sigma_cyclohexane_esrf.mat';
            app.GetNoiseFileButton = uibutton(app.NoisepartPanel, 'push');
            app.GetNoiseFileButton.ButtonPushedFcn = createCallbackFcn(app, @GetNoiseFileButtonPushed, true);
            app.GetNoiseFileButton.Text = 'Get noise files';
            app.SwitchLabel = uilabel(app.NoisepartPanel);
            app.SwitchLabel.Text = '';
            app.NoiseFreeSwitch = uiswitch(app.NoisepartPanel, 'slider');
            app.NoiseFreeSwitch.Items = {'Noise free', 'Experimental noise'};
            app.NoiseFreeSwitch.ValueChangedFcn = createCallbackFcn(app, @NoiseFreeSwitchValueChanged, true);
            app.NoiseFreeSwitch.Value = 'Experimental noise';
            app.dqEditFieldLabel = uilabel(app.NoisepartPanel);
            app.dqEditFieldLabel.Visible = 'off';
            app.dqEditFieldLabel.Text = 'dq';
            app.dqEditField = uieditfield(app.NoisepartPanel, 'numeric');
            app.dqEditField.ValueChangedFcn = createCallbackFcn(app, @dqEditFieldValueChanged, true);
            app.dqEditField.Visible = 'off';
            app.dqEditField.Value = 0.1;
            app.qMAXEditFieldLabel = uilabel(app.NoisepartPanel);
            app.qMAXEditFieldLabel.Visible = 'off';
            app.qMAXEditFieldLabel.Text = 'qMAX';
            app.qMAXEditField = uieditfield(app.NoisepartPanel, 'numeric');
            app.qMAXEditField.Limits = [1 20];
            app.qMAXEditField.ValueChangedFcn = createCallbackFcn(app, @qMAXEditFieldValueChanged, true);
            app.qMAXEditField.Visible = 'off';
            app.qMAXEditField.Value = 10;
            app.qMINEditFieldLabel = uilabel(app.NoisepartPanel);
            app.qMINEditFieldLabel.Visible = 'off';
            app.qMINEditFieldLabel.Text = 'qMIN';
            app.qMINEditField = uieditfield(app.NoisepartPanel, 'numeric');
            app.qMINEditField.Limits = [1e-12 Inf];
            app.qMINEditField.ValueChangedFcn = createCallbackFcn(app, @qMINEditFieldValueChanged, true);
            app.qMINEditField.Visible = 'off';
            app.qMINEditField.Value = 0.1;
            app.ConfigurationsFilesPanel = uipanel(app.main);
            app.ConfigurationsFilesPanel.Title = 'Configurations Files';
            app.ConfigurationFileEditField = uitextarea(app.ConfigurationsFilesPanel);
            app.ConfigurationFileEditField.ValueChangedFcn = createCallbackFcn(app, @ConfigurationFileEditFieldValueChanged, true);
            app.ConfigurationFileEditField.Editable = 'off';
            app.LoadConfigurationButton = uibutton(app.ConfigurationsFilesPanel, 'push');
            app.LoadConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @LoadConfigurationButtonButtonPushed, true);
            app.LoadConfigurationButton.Text = 'Load config';
            app.SaveConfigurationButton = uibutton(app.ConfigurationsFilesPanel, 'push');
            app.SaveConfigurationButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigurationButtonButtonPushed, true);
            app.SaveConfigurationButton.Text = 'Save config';
            app.SimulateScubeButton = uibutton(app.main, 'push');
            app.SimulateScubeButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateScubeButtonButtonPushed, true);
            app.SimulateScubeButton.Text = {'S-cube '; 'Simulation'};
            app.ThecageSoluteSolventcrosstermPanel = uipanel(app.main);
            app.ThecageSoluteSolventcrosstermPanel.Title = 'The cage (Solute-Solvent cross) term ';
            app.CagefilesnameLabel = uilabel(app.ThecageSoluteSolventcrosstermPanel);
            app.CagefilesnameLabel.Visible = 'off';
            app.CagefilesnameLabel.Text = 'Cage files name';
            app.CageFileEditField = uieditfield(app.ThecageSoluteSolventcrosstermPanel, 'text');
            app.CageFileEditField.ValueChangedFcn = createCallbackFcn(app, @CageFileEditFieldValueChanged, true);
            app.CageFileEditField.Visible = 'off';
            app.CageFileEditField.Value = 'azobenzene/cage_Azo_tS0_cS0.dat';
            app.CageprocessmethodKnobLabel = uilabel(app.ThecageSoluteSolventcrosstermPanel);
            app.CageprocessmethodKnobLabel.Text = 'Cage process method';
            app.CageMethodknob = uiknob(app.ThecageSoluteSolventcrosstermPanel, 'discrete');
            app.CageMethodknob.Items = {'None', 'Hard-spheres', 'MD simulations'};
            app.CageMethodknob.ValueChangedFcn = createCallbackFcn(app, @CageMethodknobValueChanged, true);
            app.CageMethodknob.Value = 'Hard-spheres';
            app.GetCageButton = uibutton(app.ThecageSoluteSolventcrosstermPanel, 'push');
            app.GetCageButton.ButtonPushedFcn = createCallbackFcn(app, @GetCageButtonButtonPushed, true);
            app.GetCageButton.Visible = 'off';
            app.GetCageButton.Text = 'Get Cage files';
            app.SamplingdrEditFieldLabel = uilabel(app.ThecageSoluteSolventcrosstermPanel);
            app.SamplingdrEditFieldLabel.Text = 'Sampling dr';
            app.SamplingDrEditField = uieditfield(app.ThecageSoluteSolventcrosstermPanel, 'numeric');
            app.SamplingDrEditField.Limits = [0.001 20];
            app.SamplingDrEditField.ValueChangedFcn = createCallbackFcn(app, @SamplingDrEditFieldValueChanged, true);
            app.SamplingDrEditField.Value = 0.1;
            app.SimulateScubeBatchButton = uibutton(app.main, 'push');
            app.SimulateScubeBatchButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateScubeBatchButtonButtonPushed, true);
            app.SimulateScubeBatchButton.Text = {'S-cube'; 'Simulation '; '(Batch process)'};
            app.SensitivityplotsPanel = uipanel(app.main);
            app.SensitivityplotsPanel.Title = 'Sensitivity Plots - Parameters for Simulation';
            app.AtomicPositionLabel = uilabel(app.SensitivityplotsPanel);
            app.AtomicPositionLabel.Text = {'Atomic'; 'Position'};
            app.AtomicPositionSwitch = uiswitch(app.SensitivityplotsPanel, 'rocker');
            app.AtomicPositionSwitch.ValueChangedFcn = createCallbackFcn(app, @AtomicPositionSwitchValueChanged, true);
            app.AtomicPositionSwitch.Value = 'On';
            app.InternuclearDistanceSwitchLabel = uilabel(app.SensitivityplotsPanel);
            app.InternuclearDistanceSwitchLabel.Text = {'Internuclear'; 'Distance'};
            app.InternuclearDistanceSwitch = uiswitch(app.SensitivityplotsPanel, 'rocker');
            app.InternuclearDistanceSwitch.ValueChangedFcn = createCallbackFcn(app, @InternuclearDistanceSwitchValueChanged, true);
            app.InternuclearDistanceSwitch.Value = 'On';
            app.AtomicPositionLamp = uilamp(app.SensitivityplotsPanel);
            app.InternuclearDistanceLamp = uilamp(app.SensitivityplotsPanel);
            app.SpiralsamplingnumberEditFieldLabel = uilabel(app.SensitivityplotsPanel);
            app.SpiralsamplingnumberEditFieldLabel.Text = 'Spiral sampling number';
            app.SpiralSamplingNumberEditField = uieditfield(app.SensitivityplotsPanel, 'numeric');
            app.SpiralSamplingNumberEditField.Limits = [2 Inf];
            app.SpiralSamplingNumberEditField.RoundFractionalValues = 'on';
            app.SpiralSamplingNumberEditField.ValueChangedFcn = createCallbackFcn(app, @SpiralSamplingNumberEditFieldValueChanged, true);
            app.SpiralSamplingNumberEditField.Value = 2;
            app.ChangingnucleardistanceLabel = uilabel(app.SensitivityplotsPanel);
            app.ChangingnucleardistanceLabel.Text = 'Changing nuclear distance (Å)';
            app.ChangingNuclearDistanceEditField = uieditfield(app.SensitivityplotsPanel, 'numeric');
            app.ChangingNuclearDistanceEditField.Limits = [0 Inf];
            app.ChangingNuclearDistanceEditField.ValueChangedFcn = createCallbackFcn(app, @ChangingNuclearDistanceEditFieldValueChanged, true);
            app.ChangingNuclearDistanceEditField.Value = 0.05;
            app.SpiralradiusLabel = uilabel(app.SensitivityplotsPanel);
            app.SpiralradiusLabel.Text = 'Spiral radius (Å)';
            app.SpiralRadiusEditField = uieditfield(app.SensitivityplotsPanel, 'numeric');
            app.SpiralRadiusEditField.Limits = [0 Inf];
            app.SpiralRadiusEditField.ValueChangedFcn = createCallbackFcn(app, @SpiralRadiusEditFieldValueChanged, true);
            app.SpiralRadiusEditField.Value = 0.1;
            app.SimulateSensitivityButton = uibutton(app.main, 'push');
            app.SimulateSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateSensitivityButtonPushed, true);
            app.SimulateSensitivityButton.Text = {'Sensitivity'; 'Simulation'};
            app.Image = uiimage(app.main);
            app.Image.ImageSource = 'KAIST_logo.png';
            app.Image2 = uiimage(app.main);
            app.Image2.ImageSource = 'ibs_logo.png';
            % Show the figure after all components are created
            app.main.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Scube

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.main)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.main)
        end
    end
end